classdef Braking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        plotDropDownLabel  matlab.ui.control.Label
        plotDropDown       matlab.ui.control.DropDown
        SpeedCheck         matlab.ui.control.CheckBox
        GxCheck            matlab.ui.control.CheckBox
        RearBPCheck        matlab.ui.control.CheckBox
        FrontBPCheck       matlab.ui.control.CheckBox
        BrakeBalanceCheck  matlab.ui.control.CheckBox
        Slidergps          matlab.ui.control.Slider
        Sliderdebut        matlab.ui.control.Slider
        Sliderfin          matlab.ui.control.Slider
        DebutLabel         matlab.ui.control.Label
        FinLabel           matlab.ui.control.Label
        GPSCheck           matlab.ui.control.CheckBox
        Eq                 matlab.ui.control.Label
    end


    properties (Access = private)
        Bias % pourcentage de frein avant
        appGPS %app GPS
        CallingApp %app datavisu
        t % booléen pour savoir si on trace enfonction du temps
        L % liste pour savoir ce que l'on trace 
        
    end

    methods (Access = private)
        function i = findi(~, L, val)
            %Cette fonction renvoie le premier indice auquel se trouve une valeur de la liste L supérieur à val
            i=0;
            k=1;
            while (i==0) && (k<=length(L))
                if L(k)>=val
                    i=k;
                end
                k=k+1;
            end
            if i==0
                i=length(L);
            end
        end
        function Majplot(app,val)
            
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            app.GxCheck.Visible='off';
            app.Speedcheck.Visible='off';
            app.RearBPCheck.Visible='off';
            app.FrontBPcheck.Visible='off';
            app.Slidergps.Visible='off';
            app.Sliderdebut.Visible='off';
            app.Sliderfin.Visible='off';
            app.BrakeBalanceCheck.Visible = 'off';
            app.GPSCheck.Visible = 'off';
            for i=1:length(app.CallingApp.AccelX_GPS)
                app.Bias(i)=app.CallingApp.BPF(i)/(app.CallingApp.BPF(i) + app.CallingApp.BPR(i));
            end
            
        end

        % Value changed function: plotDropDown
        function plotDropDownValueChanged(app, event)
            value = app.plotDropDown.Value;
            
            if strcmp(value,'Rear Pressure / Front Pressure')
                delete(app.appGPS);
                app.Gxcheck.Visible='off';
                app.Speedcheck.Visible='off';
                app.RearBPCheck.Visible='off';
                app.FrontBPcheck.Visible='off';
                app.Slidergps.Visible='off';
                app.Sliderdebut.Visible='off';
                app.Sliderfin.Visible='off';
                app.BrakeBalanceCheck.Visible = 'off';
                app.GPSCheck.Visible = 'off';
                plot(app.UIAxes, app.CallingApp.BPF, app.CallingApp.BPR, '+');
                xlabel(app.UIAxes,'Front Brake Pressure');
                ylabel(app.UIAxes,'Rear Brake Pressure');
                p = polyfit(app.CallingApp.BPF, app.CallingApp.BPR,1);
                plot(app.UIAxes, app.CallingApp.BPF, app.CallingApp.BPF.*p(1)+p(2));
                ap.Eq.Text = strcat
            elseif strcmp(value,'Bias / Front Pressure')
                delete(app.appGPS);
                app.Gxcheck.Visible='off';
                app.Speedcheck.Visible='off';
                app.RearBPCheck.Visible='off';
                app.FrontBPcheck.Visible='off';
                app.Slidergps.Visible='off';
                app.Sliderdebut.Visible='off';
                app.Sliderfin.Visible='off';
                app.BrakeBalanceCheck.Visible = 'off';
                app.GPSCheck.Visible = 'off';
                plot(app.UIAxes, app.CallingApp.BPF, app.Bias, '+');
                xlabel(app.UIAxes,'Front Brake Pressure');
                ylabel(app.UIAxes,'Brake Balance');
            elseif strcmp(value,'data / time')
                app.t = 1;
                app.L =[0 0 0 0 0];
                delete(app.appGPS);
                app.Gxcheck.Visible='on';
                app.Gxcheck.Value = 0;
                app.Speedcheck.Visible='on';
                app.Speedcheck.Value = 0;
                app.RearBPCheck.Visible='on';
                app.RearBPCheck.Value = 0;
                app.FrontBPcheck.Visible='on';
                app.FrontBPcheck.Value = 0;
                app.BrakeBalanceCheck.Visible = 'on';
                app.BrakeBalanceCheck.Value = 0;
                app.GPSCheck.Visible = 'on';
                app.GPSCheck.Value = 0;
                app.Slidergps.Limits = [0 app.CallingApp.Time(end)];
                app.Slidergps.Visible='on';
                app.Slidergps.Value = 0;
                app.Sliderdebut.Limits = [0 app.CallingApp.Time(end)];
                app.Sliderdebut.Visible='on';
                app.Sliderdebut.Value = 0;
                app.Sliderfin.Limits = [0 app.CallingApp.Time(end)];
                app.Sliderfin.Visible='on';
                app.Sliderfin.Value = app.CallingApp.Time(end);
                
                xlabel(app.UIAxes,'time (s)');
                ylabel(app.UIAxes,'');
                Majplot(app,0);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.plotDropDownLabel = uilabel(app.UIFigure);
            app.plotDropDownLabel.Text = 'plot :';
            app.plotDropDown = uidropdown(app.UIFigure);
            app.plotDropDown.Items = {'...', 'Rear Pressure / Front Pressure', 'Bias / Front Pressure', 'data / time', 'data / distance'};
            app.plotDropDown.ValueChangedFcn = createCallbackFcn(app, @plotDropDownValueChanged, true);
            app.plotDropDown.Value = '...';
            app.SpeedCheck = uicheckbox(app.UIFigure);
            app.SpeedCheck.Text = 'Speed';
            app.GxCheck = uicheckbox(app.UIFigure);
            app.GxCheck.Text = 'Gx';
            app.RearBPCheck = uicheckbox(app.UIFigure);
            app.RearBPCheck.Text = 'Rear BP';
            app.FrontBPCheck = uicheckbox(app.UIFigure);
            app.FrontBPCheck.Text = 'Front BP';
            app.BrakeBalanceCheck = uicheckbox(app.UIFigure);
            app.BrakeBalanceCheck.Text = 'Brake Balance';
            app.Slidergps = uislider(app.UIFigure);
            app.Sliderdebut = uislider(app.UIFigure);
            app.Sliderfin = uislider(app.UIFigure);
            app.DebutLabel = uilabel(app.UIFigure);
            app.DebutLabel.Text = 'Début';
            app.FinLabel = uilabel(app.UIFigure);
            app.FinLabel.Text = 'Fin';
            app.GPSCheck = uicheckbox(app.UIFigure);
            app.GPSCheck.Text = 'GPS';
            app.Eq = uilabel(app.UIFigure);
            app.Eq.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Braking(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end