classdef Cornering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        plot                        matlab.ui.control.UIAxes
        SteeringCheck               matlab.ui.control.CheckBox
        Yawcheck                    matlab.ui.control.CheckBox
        AverageundersteerLabel      matlab.ui.control.Label
        Average                     matlab.ui.control.Label
        OversteeringtendencyLabel   matlab.ui.control.Label
        UndersteeringtendencyLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp % Datavisu
        Balance_Yaw % balance du véhicule calculer à partir du Yaw
        M_Yaw % Liste des balance à prendre dans la moyenne
        Balance_Steer % balance du véhicule calculer à partir du volant
        M_steer % liste de sbalance à prendre en compte dans la moyenne
        Steering10 %Sttering échantillonné en 10Hz
        WheelBase = 1.575; %Wheel base en m
    end

    methods (Access = private)
    
        function majPlot(app,col)
            z = zeros(size(app.CallingApp.X'));
            col = col';  % This is the color, vary with x in this case.
            surface(app.plot, [app.CallingApp.X';app.CallingApp.X'],[app.CallingApp.Y';app.CallingApp.Y'],[z;z],[col;col],'facecol','no','edgecol','interp','linew',2);
            c = colorbar(app.plot);
            xlabel(app.plot, 'X (m)')
            ylabel(app.plot, 'Y (m)')
            c.Label.String = 'Balance (°)';
            axis(app.plot,[min(app.CallingApp.X)-10,max(app.CallingApp.X)+10,min(app.CallingApp.Y)-10,max(app.CallingApp.Y)+10]);
            set(app.plot,'Visible','off')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            app.Yawcheck.Value = 1;
            app.SteeringCheck.Value = 0;
            
            %Passage de l'angle au volant en 10hz
            %app.Steering10 = interp1(app.CallingApp.Steering,app.CallingApp.Tacc,app.CallingApp.Tgps);
            
            %Angle des roues
            
            for k=1:length(app.CallingApp.Speed)
                if app.CallingApp.Speed(k)>5 %si la vitesse dépasse 5 km/h (pas d'intérêt sinon)
                    app.Balance_Yaw(k) = abs(app.CallingApp.Yaw_GPS(k))-abs(9.81*app.CallingApp.AccelX_GPS(k)/(0.277*app.CallingApp.Speed(k)))*57.3;
                    app.M_Yaw = [app.M_Yaw app.Balance_Yaw(k)];
                    %app.Balance_Steer(k) = app.WheelAngle - abs(57.3*app.WheelBase*9.81*app.CallingApp.AccelY_GPS(k)/(app.CallingApp.Speed(k)^2)*3.6^2);
                    %app.M_Steer = [app.M_Steer app.Balance_Steer(k)];
                else
                    app.Balance_Yaw(k) = 0;
                    app.Balance_Steer(k) = 0;
                end
            end
            
            app.Balance_Yaw = app.Balance_Yaw';
            assignin('base','balance_yaw',app.Balance_Yaw);
            majPlot(app,app.Balance_Yaw)
            app.Average.Text = num2str(mean(app.M_Yaw,'native'));
        end

        % Value changed function: Yawcheck
        function YawcheckValueChanged(app, event)
            value = app.Yawcheck.Value;
            if value == 1
                app.SteeringCheck.Value = 0;
                majPlot(app,app.Balance_Yaw)
                app.Average.Text = num2str(mean(app.M_Yaw,'native'));
            end
        end

        % Value changed function: SteeringCheck
        function SteeringCheckValueChanged(app, event)
            value = app.SteeringCheck.Value;
            if value == 1
                app.YawCheck.Value = 0;
                majPlot(app,app.Balance_Yaw)
                app.Average.Text = num2str(mean(app.M_Steer,'native'));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.plot = uiaxes(app.UIFigure);
            title(app.plot, 'Understeer angle (°) projected on the track')
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.SteeringCheck = uicheckbox(app.UIFigure);
            app.SteeringCheck.ValueChangedFcn = createCallbackFcn(app, @SteeringCheckValueChanged, true);
            app.SteeringCheck.Text = 'Steering calcul (pas dispo encore)';
            app.Yawcheck = uicheckbox(app.UIFigure);
            app.Yawcheck.ValueChangedFcn = createCallbackFcn(app, @YawcheckValueChanged, true);
            app.Yawcheck.Text = 'Yaw calcul';
            app.Yawcheck.Value = true;
            app.AverageundersteerLabel = uilabel(app.UIFigure);
            app.AverageundersteerLabel.Text = 'Average understeer (°) =  ';
            app.Average = uilabel(app.UIFigure);
            app.OversteeringtendencyLabel = uilabel(app.UIFigure);
            app.OversteeringtendencyLabel.Text = {'Oversteering'; 'tendency'};
            app.UndersteeringtendencyLabel = uilabel(app.UIFigure);
            app.UndersteeringtendencyLabel.Text = {'Understeering'; 'tendency'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cornering(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end