classdef Dampers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TravelCheckBox           matlab.ui.control.CheckBox
        SpeedCheckBox            matlab.ui.control.CheckBox
        SpeedHistogramCheckBox   matlab.ui.control.CheckBox
        AxesFL                   matlab.ui.control.UIAxes
        AxesFR                   matlab.ui.control.UIAxes
        AxesRL                   matlab.ui.control.UIAxes
        AxesRR                   matlab.ui.control.UIAxes
        SuspensionSensorsLabel   matlab.ui.control.Label
        FilterorderSpinnerLabel  matlab.ui.control.Label
        FilterorderSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        CallingApp %Datavisu
        XFLf % filtered data
        XFRf
        XRLf
        XRRf
        Filter_order
        VFL
        VFR
        VRL
        VRR
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            app.TravelCheckBox.Value = 1;
            app.SpeedCheckBox.Value = 0;
            app.SpeedCheckBox.Value = 0;
            %Data filter : Moving average
            app.Filter_order = 100 %app.FilterorderSpinner.Value;
            %[a,b] = butter(app.Filter_order,(250/2),'low');
            a = [1];
            b = ones(1,app.Filter_order)*1/app.Filter_order;
            app.XFLf = filter(b,a,app.CallingApp.AccelX);
            app.XFRf = filter(b,a,app.CallingApp.AccelX);
            app.XRLf = filter(b,a,app.CallingApp.AccelX);
            app.XRRf = filter(b,a,app.CallingApp.AccelX);
            app.VFL = [];
                app.VFR = [];
                app.VRL = [];
                app.VRR = [];
%             for k=1:length(app.XFLf)-1
%                 app.VFL = [app.VFL app.XFLf(k+1)-app.XFLf(k)/(1/length(app.XFLf))];
%                 app.VFR = 0
%                 app.VRL = 0
%                 app.VRR = 0
%             end 
            assignin('base',"XFL",app.XFLf)
            plot(app.AxesFL,app.XFLf)
            histogram(app.AxesFR,app.XFRf)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TravelCheckBox = uicheckbox(app.UIFigure);
            app.TravelCheckBox.Text = 'Travel';
            app.SpeedCheckBox = uicheckbox(app.UIFigure);
            app.SpeedCheckBox.Text = 'Speed';
            app.SpeedHistogramCheckBox = uicheckbox(app.UIFigure);
            app.SpeedHistogramCheckBox.Text = 'Speed Histogram';
            app.AxesFL = uiaxes(app.UIFigure);
            title(app.AxesFL, 'FL', 'Interpreter', 'latex')
            xlabel(app.AxesFL, 'X', 'Interpreter', 'latex')
            ylabel(app.AxesFL, 'Y', 'Interpreter', 'latex')
            app.AxesFR = uiaxes(app.UIFigure);
            title(app.AxesFR, 'FR', 'Interpreter', 'latex')
            xlabel(app.AxesFR, 'X', 'Interpreter', 'latex')
            ylabel(app.AxesFR, 'Y', 'Interpreter', 'latex')
            app.AxesRL = uiaxes(app.UIFigure);
            title(app.AxesRL, 'RL', 'Interpreter', 'latex')
            xlabel(app.AxesRL, 'X', 'Interpreter', 'latex')
            ylabel(app.AxesRL, 'Y', 'Interpreter', 'latex')
            app.AxesRR = uiaxes(app.UIFigure);
            title(app.AxesRR, 'RR', 'Interpreter', 'latex')
            xlabel(app.AxesRR, 'X', 'Interpreter', 'latex')
            ylabel(app.AxesRR, 'Y', 'Interpreter', 'latex')
            app.SuspensionSensorsLabel = uilabel(app.UIFigure);
            app.SuspensionSensorsLabel.Text = 'Suspension Sensors';
            app.FilterorderSpinnerLabel = uilabel(app.UIFigure);
            app.FilterorderSpinnerLabel.Text = 'Filter order';
            app.FilterorderSpinner = uispinner(app.UIFigure);
            app.FilterorderSpinner.Limits = [0 6];
            app.FilterorderSpinner.Value = 3;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Dampers(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end