classdef Gforce < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure                    matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ChoixduplotDropDownLabel  matlab.ui.control.Label
        Choixduplot               matlab.ui.control.DropDown
        Gxcheck                   matlab.ui.control.CheckBox
        Gycheck                   matlab.ui.control.CheckBox
        GcombCheck                matlab.ui.control.CheckBox
    end


    properties (Access = private)
        CallingApp % app qui à ouvert celle-ci
        Accelcomb % Norme de GX et GY
        Accelcomb_GPS % la même mais seulement pour les temps gps
        L %liste des cases cochées
        t % booléen qui définit si on trace en fonction du temps ou de la distance
    end

    methods (Access = private)
    
        function Majplot(app)
            hold(app.UIAxes,'off');
            if app.L(1)
                if app.t==1
                    plot(app.UIAxes, app.CallingApp.Tacc, app.CallingApp.AccelX,'DisplayName','Longitudinal G');
                else
                    plot(app.UIAxes, app.CallingApp.Tgps, app.CallingApp.AccelX_GPS,'DisplayName','Longitudinal G');
                end
                hold(app.UIAxes,'on');
            end
            if app.L(2)
                if app.t==1
                    plot(app.UIAxes, app.CallingApp.Tacc, app.CallingApp.AccelY,'DisplayName','Lateral G');
                else
                    plot(app.UIAxes, app.CallingApp.Tgps, app.CallingApp.AccelY_GPS,'DisplayName','Lateral G');
                end
                hold(app.UIAxes,'on');
            end
            if app.L(3)
                if app.t==1
                    plot(app.UIAxes, app.CallingApp.Tacc, app.Accelcomb,'DisplayName','Combined G');
                else
                    plot(app.UIAxes, app.CallingApp.Tgps, app.CallingApp.Accelcomb_GPS,'DisplayName','Combined G');
                end
                hold(app.UIAxes,'on');
            end
            legend(app.UIAxes,'show')
            hold(app.UIAxes,'off');
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            app.Gxcheck.Visible='off';
            app.Gycheck.Visible='off';
            app.GcombCheck.Visible='off';
            for i=1:length(app.CallingApp.AccelX_GPS)
                app.Accelcomb_GPS(i)=sqrt(app.CallingApp.AccelX_GPS(i)^2+app.CallingApp.AccelY_GPS(i)^2);
            end
            for i=1:length(app.CallingApp.AccelX)
                app.Accelcomb(i)=sqrt(app.CallingApp.AccelX(i)^2+app.CallingApp.AccelY(i)^2);
            end
        end

        % Value changed function: Choixduplot
        function ChoixduplotValueChanged(app, event)
            value = app.Choixduplot.Value;
            
            if strcmp(value,'Traction Circle')
                legend(app.UIAxes,'off')
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                hold(app.UIAxes,'on')
                b = max(abs(app.CallingApp.AccelX));
                a = max(abs(app.CallingApp.AccelY));
                x_ell = (-a:0.01:a);
                y_ell = b*sqrt(1 - (x_ell/a).^2);
                plot(app.UIAxes, app.CallingApp.AccelY, app.CallingApp.AccelX, '+');
                plot(app.UIAxes, x_ell, y_ell, 'r')
                plot(app.UIAxes, x_ell, -y_ell, 'r')
                xlabel(app.UIAxes,'Lateral G');
                ylabel(app.UIAxes,'Longitudinal G');
            elseif strcmp(value,'Accels / Temps')
                app.t = 1;
                app.L=[0 0 0];
                app.Gxcheck.Visible='on';
                app.Gycheck.Visible='on';
                app.GcombCheck.Visible='on';
                app.Gxcheck.Value= 0;
                app.Gycheck.Value= 0;
                app.GcombCheck.Value= 0;
                plot(app.UIAxes,0,0);
                xlabel(app.UIAxes,'time(s)');
                ylabel(app.UIAxes,'');
            elseif strcmp(value,'Accels / Distance')
                app.t = 0;
                app.L=[0 0 0];
                app.Gxcheck.Visible='on';
                app.Gycheck.Visible='on';
                app.GcombCheck.Visible='on';
                app.Gxcheck.Value= 0;
                app.Gycheck.Value= 0;
                app.GcombCheck.Value= 0;
                plot(app.UIAxes,0,0);
                xlabel(app.UIAxes,'distance (m)');
                ylabel(app.UIAxes,'');
            elseif strcmp(value,'Gx / Speed')
                legend(app.UIAxes,'off')
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                plot(app.UIAxes, app.CallingApp.Speed, app.CallingApp.AccelX_GPS, '+');
                xlabel(app.UIAxes,'speed (km/h)');
                ylabel(app.UIAxes,'Longitudinal G');
            elseif strcmp(value,'Gy / Speed')
                legend(app.UIAxes,'off')
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                plot(app.UIAxes, app.CallingApp.Speed, app.CallingApp.AccelY_GPS, '+');
                xlabel(app.UIAxes,'speed (km/h)');
                ylabel(app.UIAxes,'Lateral G');
            elseif strcmp(value, 'Gcomb / Speed')
                legend(app.UIAxes,'off')
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                plot(app.UIAxes, app.CallingApp.Speed, app.Accelcomb_GPS, '+');
                xlabel(app.UIAxes,'speed (km/h)');
                ylabel(app.UIAxes,'Combined G');
            end
        end

        % Value changed function: Gxcheck
        function GxcheckValueChanged(app, event)
            value = app.Gxcheck.Value;
            if value == 1
                app.L(1) = 1;
            else
                app.L(1) = 0;
            end   
            Majplot(app);
        end

        % Value changed function: Gycheck
        function GycheckValueChanged(app, event)
            value = app.Gycheck.Value;
            if value == 1
               app.L(2) = 1;
            else
               app.L(2) = 0;
            end 
            Majplot(app);
        end

        % Value changed function: GcombCheck
        function GcombCheckValueChanged(app, event)
            value = app.GcombCheck.Value;
            if value == 1
                app.L(3) = 1;
            else
                app.L(3) = 0;
            end 
            Majplot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure = uifigure;
            app.figure.Name = 'G-force';
            app.UIAxes = uiaxes(app.figure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ChoixduplotDropDownLabel = uilabel(app.figure);
            app.ChoixduplotDropDownLabel.Text = 'Choix du plot';
            app.Choixduplot = uidropdown(app.figure);
            app.Choixduplot.Items = {'...', 'Traction Circle', 'Accels / Temps', 'Accels / DIstance', 'Gx / Speed', 'Gy / Speed', 'Gcomb / Speed'};
            app.Choixduplot.ValueChangedFcn = createCallbackFcn(app, @ChoixduplotValueChanged, true);
            app.Choixduplot.Value = '...';
            app.Gxcheck = uicheckbox(app.figure);
            app.Gxcheck.ValueChangedFcn = createCallbackFcn(app, @GxcheckValueChanged, true);
            app.Gxcheck.Text = 'Gx';
            app.Gycheck = uicheckbox(app.figure);
            app.Gycheck.ValueChangedFcn = createCallbackFcn(app, @GycheckValueChanged, true);
            app.Gycheck.Text = 'Gy';
            app.GcombCheck = uicheckbox(app.figure);
            app.GcombCheck.ValueChangedFcn = createCallbackFcn(app, @GcombCheckValueChanged, true);
            app.GcombCheck.Text = 'Gcomb';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Gforce(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure)
        end
    end
end