classdef Temps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
    end


    properties (Access = private)
        Callingapp % Description
    end

    methods (Access = private)
    
        function B = LapSelect(app,Lapnb,A,gps)
            B = [];
            if gps==0
                if isa(app.Callingapp.LapTime,'double')
                    B=A;
                else
                    for k=find(app.Callingapp.Tacc==app.Callingapp.TLap(Lapnb)):find(app.Callingapp.Tacc==app.Callingapp.TLap(Lapnb+1))
                        B = [B A(k)];
                    end
                end
            elseif gps==1
                if isa(app.Callingapp.LapTime,'double')
                    B=A;
                elseif lenght([app.Callingapp.LapTime])>Lapnb
                    for k=find(app.Callingapp.Tgps==app.Callingapp.TLap(Lapnb)):find(app.Callingapp.Tgps==app.Callingapp.TLap(Lapnb+1))
                        B = [B A(k)];
                    end
                else
                     for k=find(app.Callingapp.Tgps==app.Callingapp.TLap(Lapnb)):length(app.Callingapp.Tgps)
                        B = [B A(k)];
                     end
                end
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.Callingapp = Mainapp;
            app.UITable.Data(:,1)= 1:1:app.Callingapp.Lap(end);
            app.UITable.Data(:,2) = round(app.Callingapp.LapTime,2);
            
            for k=1:app.Callingapp.Lap(end)
                digits(3);
                app.UITable.Data(k,3) = vpa(mean(LapSelect(app,k,app.Callingapp.Speed,1),'native'));
                app.UITable.Data(k,4) = vpa(mean(LapSelect(app,k,app.Callingapp.RPM,0),'native'));
                app.UITable.Data(k,5) = vpa(mean(LapSelect(app,k,app.Callingapp.TPS,0),'native'));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Lap_number'; 'Time'; 'Average_speed'; 'Average_RPM'; 'Average_TPS'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Temps(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end