classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Gforce                    matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ChoixduplotDropDownLabel  matlab.ui.control.Label
        Choixduplot               matlab.ui.control.DropDown
        Gxcheck                   matlab.ui.control.CheckBox
        Gycheck                   matlab.ui.control.CheckBox
        GcombCheck                matlab.ui.control.CheckBox
    end


    properties (Access = private)
        CallingApp % app qui à ouvert celle-ci
        Accelcomb % Norme de GX et GY
        Accelcomb_GPS % la même mais seulement pour les temps gps
        L %liste des cases chée avec leurs ordres
    end

    methods (Access = private)
    
        function MajLeg(app)
            if 
            end
        end
    
        function 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            app.Gxcheck.Visible='off';
            app.Gycheck.Visible='off';
            app.GcombCheck.Visible='off';
            for i=1:length(app.CallingApp.AccelX_GPS)
                app.Accelcomb_GPS(i)=sqrt(app.CallingApp.AccelX_GPS^2+app.CallingApp.AccelY_GPS^2);
            end
            for i=1:length(app.CallingApp.AccelX)
                app.Accelcomb(i)=sqrt(app.CallingApp.AccelX^2+app.CallingApp.AccelY^2);
            end
        end

        % Value changed function: Choixduplot
        function ChoixduplotValueChanged(app, event)
            value = app.Choixduplot.Value;
            
            if strcmp(value,'Traction Circle')
                app.CallingApp = Mainapp;
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                plot(app.UIAxes, app.CallingApp.AccelY, app.CallingApp.AccelX, '+');
                XLabel = 'Lateral G';
                YLabel = 'Logitudinal G';
            elseif strcmp(value,'Accel / Temps')
                app.Gxcheck.Visible='on';
                app.Gycheck.Visible='on';
                app.GcombCheck.Visible='on';
                app.Gxcheck.Value= 0;
                app.Gycheck.Value= 0;
                app.GcombCheck.Value= 0;
                plot(app.UIAxes,0,0);
                XLabel = 'temps (s)';
                YLabel = '';
            elseif strcmp(value,'Gx / Speed')
                app.CallingApp = Mainapp;
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                plot(app.UIAxes, app.CallingApp.Speed, app.CallingApp.AccelX_GPS, '+');
                XLabel = 'speed (km/h)';
                YLabel = 'Logitudinal G';
            elseif strcmp(value,'Gy / Speed')
                app.CallingApp = Mainapp;
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                plot(app.UIAxes, app.CallingApp.Speed, app.CallingApp.AccelY_GPS, '+');
                XLabel = 'speed (km/h)';
                YLabel = 'Lateral G';
            elseif strcmp(value, 'Gcomb / Speed')
                app.CallingApp = Mainapp;
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                plot(app.UIAxes, app.CallingApp.Speed, app.Accelcomb_GPS, '+');
                XLabel = 'speed (km/h)';
                YLabel = 'Lateral G';
            end
        end

        % Value changed function: Gxcheck
        function GxcheckValueChanged(app, event)
            value = app.Gxcheck.Value;
            if value == 1
                hold(app.UIAxes,'on')
                plot(app.UIAxes,app.CallingApp.Tgps,app.CallingApp.AccelX);
                hold(app.UIAxes,'off')
            end                
        end

        % Value changed function: Gycheck
        function GycheckValueChanged(app, event)
            value = app.Gycheck.Value;
            if value == 1
                if ismember(1,app.L)
                    if ismember(2,app.L)
                        app.L(1) = 3;
                    else
                        app.L(1) = 2;
                    end
                else
                    app.L(1) = 1;
                end
                hold(app.UIAxes,'on')
                plot(app.UIAxes,app.CallingApp.Tgps,app.CallingApp.AccelY);
                hold(app.UIAxes,'off')
            end 
        end

        % Value changed function: GcombCheck
        function GcombCheckValueChanged(app, event)
            value = app.GcombCheck.Value;
            if value == 1
                hold(app.UIAxes,'on')
                plot(app.UIAxes,app.CallingApp.Tgps,app.Accelcomb);
                hold(app.UIAxes,'off')
            end 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Gforce = uifigure;
            app.Gforce.Name = 'G-force';
            app.UIAxes = uiaxes(app.Gforce);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ChoixduplotDropDownLabel = uilabel(app.Gforce);
            app.ChoixduplotDropDownLabel.Text = 'Choix du plot';
            app.Choixduplot = uidropdown(app.Gforce);
            app.Choixduplot.Items = {'...', 'Traction Circle', 'Accel / Temps', 'Accels / DIstance', 'Gx / Speed', 'Gy / Speed', 'Gcomb / Speed'};
            app.Choixduplot.ValueChangedFcn = createCallbackFcn(app, @ChoixduplotValueChanged, true);
            app.Choixduplot.Value = '...';
            app.Gxcheck = uicheckbox(app.Gforce);
            app.Gxcheck.ValueChangedFcn = createCallbackFcn(app, @GxcheckValueChanged, true);
            app.Gxcheck.Text = 'Gx';
            app.Gycheck = uicheckbox(app.Gforce);
            app.Gycheck.ValueChangedFcn = createCallbackFcn(app, @GycheckValueChanged, true);
            app.Gycheck.Text = 'Gy';
            app.GcombCheck = uicheckbox(app.Gforce);
            app.GcombCheck.ValueChangedFcn = createCallbackFcn(app, @GcombCheckValueChanged, true);
            app.GcombCheck.Text = 'Gcomb';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Gforce)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Gforce)
        end
    end
end