classdef datavisu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DisplayMenu                     matlab.ui.container.Menu
        tempsdistanceMenu               matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        GForces                         matlab.ui.container.Menu
        CorneringMenu                   matlab.ui.container.Menu
        VitalFunctionsMenu              matlab.ui.container.Menu
        BrakingMenu                     matlab.ui.container.Menu
        GripfactorsMenu                 matlab.ui.container.Menu
        DampersMenu                     matlab.ui.container.Menu
        PiloteMenu                      matlab.ui.container.Menu
        TempsMenu                       matlab.ui.container.Menu
        GPS                             matlab.ui.control.UIAxes
        NameofthelogfileLabel           matlab.ui.control.Label
        logfilename                     matlab.ui.control.EditField
        ImportButton                    matlab.ui.control.Button
        Duree                           matlab.ui.control.Label
        Vmax                            matlab.ui.control.Label
        Gxmax                           matlab.ui.control.Label
        Gxmin                           matlab.ui.control.Label
        Gymax                           matlab.ui.control.Label
        logLabel                        matlab.ui.control.Label
        ExportdatatotheworkspaceButton  matlab.ui.control.Button
        Ttour                           matlab.ui.control.Label
        NbTour                          matlab.ui.control.Label
        NumberofanaloginputsDropDownLabel  matlab.ui.control.Label
        anag                            matlab.ui.control.DropDown
        Importa2ndrunButton             matlab.ui.control.Button
        Info                            matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        LaptimeloggedCheckBox           matlab.ui.control.CheckBox
    end


    properties (Access = public)
        Time = [];%en s
        Tacc = [];%en s
        Tgps = [];%en s
        X = [];%en m
        Y = [];%en m
        AccelX = [];%en G
        AccelY = [];%en G
        AccelZ = [];%en G
        Yaw = [];%en deg/s
        Pitch = [];%en deg/s
        Roll = [];%en deg/s
        Latitude = [];%en °
        Longitude = [];%en °
        Speed = [];%en km/h
        distance = [];% en m
        Altitude = [];%en m
        AccelX_GPS = [];%en G
        AccelY_GPS = [];%en G
        AccelZ_GPS = [];%en G
        Yaw_GPS = [];%en deg/s
        Pitch_GPS = [];%en deg/s
        Roll_GPS = [];%en deg/s
        Steering = [];%Angle de rotation du volant en radian
        XFL = [];%déplacement des suspensions en mm
        XFR = [];
        XRL = [];
        XRR = [];
        WFL = [];%Vitesse de rotation des roues en rad/s
        WFR = [];
        WRL = [];
        WRR = [];
        BPF = [];%Pression dans le circuit de freinage en bar
        BPR = [];
        RPM = [];%vitesse de rotation du moteur en tour/min
        Gear = [];%Vitesse engangée (0 pour neutre)
        Pair = [];%pression dans le plenum en bar
        Tair = [];%Température dans le plenum en °c
        Twater = [];%Température d'eau en °c
        Poil = [];%Pression d'huile moteur
        Pfuel = [];%Pression dans le circuit d'essence au niveau des injecteurs
        Lambda = [];%Retour de la sonde lambda
        TPS = [];%déplacement de la guillotine en % (0 pour fermé et 100 pour ouvert)*
        Vbat = [];%tension de la batterie
        Lap = [];% numéro du tour précédent
        LapTime = [];%temps du tour précédent
        Sector = [];%secteur dans lequel ce trouve la voiture ??
        SectorTime = [];%temps du secteur précédent ??
        ElapsedTime = [];%temps écoulé depuis le début du tour
        CurrentLap = [];%temps en cours  
        TLap = [];%Temps (à partir de Time) de début et de fin des tours (si 3 tours, 4 valeurs)
        
        %Deuxième Run
        Time2 = [];%en s
        Tacc2 = [];%en s
        Tgps2 = [];%en s
        X2 = [];%en m
        Y2 = [];%en m
        AccelX2 = [];%en G
        AccelY2 = [];%en G
        AccelZ2 = [];%en G
        Yaw2 = [];%en deg/s
        Pitch2 = [];%en deg/s
        Roll2 = [];%en deg/s
        Latitude2 = [];%en °
        Longitude2 = [];%en °
        Speed2 = [];%en km
        distance2 = [];% en m
        Altitude2 = [];%en m
        AccelX_GPS2 = [];%en G
        AccelY_GPS2 = [];%en G
        AccelZ_GPS2 = [];%en G
        Yaw_GPS2 = [];%en deg/s
        Pitch_GPS2 = [];%en deg/s
        Roll_GPS2 = [];%en deg/s
        Steering2 = [];%Angle de rotation du volant en radian
        XFL2 = [];%déplacement des suspensions en mm
        XFR2 = [];
        XRL2 = [];
        XRR2= [];
        WFL2 = [];%Vitesse de rotation des roues en rad/s
        WFR2 = [];
        WRL2 = [];
        WRR2 = [];
        BPF2 = [];%Pression dans le circuit de freinage en bar
        BPR2 = [];
        RPM2 = [];%vitesse de rotation du moteur en tour/min
        Gear2 = [];%Vitesse engangée (0 pour neutre)
        Pair2 = [];%pression dans le plenum en bar
        Tair2 = [];%Température dans le plenum en °c
        Twater2 = [];%Température d'eau en °c
        Poil2 = [];%Pression d'huile moteur
        Pfuel2 = [];%Pression dans le circuit d'essence au niveau des injecteurs
        Lambda2 = [];%Retour de la sonde lambda
        TPS2 = [];%déplacement de la guillotine en % (0 pour fermé et 100 pour ouvert)*
        Vbat2 = [];%tension de la batterie
        Lap2 = [];% numéro du tour précédent
        LapTime2 = [];%temps du tour précédent
        Sector2 = [];%secteur dans lequel ce trouve la voiture ??
        SectorTime2 = [];%temps du secteur précédent ??
        ElapsedTime2 = [];%temps écoulé depuis le début du tour
        CurrentLap2 = [];%temps en cours  
        TLap2 = [];%Temps (à partir de Time) de début et de fin des tours (si 3 tours, 4 valeurs)
    end

    properties (Access = private)
        appCornering %application cornering
        Plot_app %application plot
        G_force %application Gforces
        appVital %application vital functions
        appBraking
        appDampers
        appTemps
        appGrip
    end


    methods (Access = private)
        function [X,Y] = lat_longi2X_Y(~, lattitude, longitude)
            % lattitude et longitude sont les données brut en ° venant des logs
            lat_red = (lattitude - mean(lattitude))*pi/180;
            long_red = (longitude - mean(longitude))*pi/180;

            R = 6378137; % Rayon standard de la terre

            X = -R .* tan(lat_red);
            Y = R .* tan(long_red);
        end
    
        function [] = track_plot(app, X, Y, speed)
            % X et Y sont des coordonnées venant de lat_longi2X_Y
            % speed est en km/h

            z = zeros(size(X'));
            col = speed';  % This is the color, vary with x in this case.
            surface(app.GPS, [X';X'],[Y';Y'],[z;z],[col;col],'facecol','no','edgecol','interp','linew',2);
            c = colorbar(app.GPS);
            xlabel(app.GPS, 'X (m)')
            ylabel(app.GPS, 'Y (m)')
            c.Label.String = 'Vitesse Km/h';
            axis(app.GPS,[min(app.X)-10,max(app.X)+10,min(app.Y)-10,max(app.Y)+10]);
            set(app.GPS,'Visible','off')
        end
        
        
        function steer = Fsteer(~, volt)
            for i=1:length(volt)
                if volt(i)<1
                    volt(i) = volt(i) + 2.61; %pour prendre en compte le comportement du capteur
                end
            end
            steer = 141.51*volt-ones(length(volt),1)*320.24; %Angle du volant à partir de la tension 
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDown.Visible = 'off';
            app.Info.Visible = 'off';
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Number of analog input
            an = str2num(app.anag.Value);
            if app.LaptimeloggedCheckBox.Value
                nbgps = 10;
            else
                nbgps = 3;
            end
            % Import the data en remplacant les vides par des NaN
            raw  = dlmread_empty(strcat(app.logfilename.Value, '.log'),',',1,0,NaN);
            
            % Sélection des données enregistrée à 50 Hz
            data50 = []; % données enregistrée à 50Hz
            for i= 1:length(raw)
                if abs(raw(i,an+3)) >= 0 % On ne garde que les moments ou Les G sont pris
                    data50 = [data50; raw(i,1:31+an)];
                end
            end

            % GPS data selection
            % data_gps = [time:1 lattitude:2 longitude:3 speed:4 height:5 distance:6 Ax:7 Ay:8 Az:9 Yaw:10 Pitch:11 Roll:12]
            data_gps = []; 
            for i=1:length(raw) %pour toutes les données
                if abs(raw(i,an+32)) > 0   %Si le GPS ne s'initialise plus
                    GPSi = [raw(i,1) raw(i, an+32:an+32+nbgps)];
                    data_gps = [data_gps; GPSi];
                end
            end
            assignin('base',"Time",app.Time);
            % data50 = [time(ms):1  Ax(g):9  Ay(g):10  Az(g):11  Yaw(°/s):12  Pitch(°/s):13  Roll(°/s):14]
            
            
            % Allocate imported array to column variable names
            app.Time = (raw(:,1)-raw(1,1))/1000; % enregistrement de Time en s
            %Accels
            app.Tacc = (data50(:,1)-data50(1,1))/1000; %en seconde et remis à zeros
            app.AccelX = -data50(:,an+3); %en G avec un moins car le boitier était à l'envers
            app.AccelY = data50(:,an+4); %en G
            app.AccelZ = data50(:,an+5); %en G
            app.Yaw = data50(:,an+6); %en deg/s
            app.Pitch = data50(:,an+7); %en deg/s
            app.Roll = data50(:,an+8); %en deg/s
            
            
            %Changelent de repère pour Optimus
            Ar = deg2rad(127); % angle d'inclinaison du RaceCapture
            R = [[cos(Ar)  sin(Ar)],
                 [-sin(Ar) cos(Ar)]];
            A = [app.AccelX, app.AccelZ]*R;
            app.AccelX = A(:,1);
            app.AccelZ = A(:,2);
            B = [app.Roll,app.Yaw]*R;
            app.Roll = B(:,1);
            app.Yaw = B(:,2);


            %Gps
            app.Tgps = (data_gps(:,1)-data50(1,1))/1000; %en seconde et remis au niveau des accels
            app.Latitude = data_gps(:,2);
            app.Longitude = data_gps(:,3);
            app.Speed = data_gps(:,4); %en km/h
            app.Altitude = data_gps(:,5)*0.3048; % en m
            if app.LaptimeloggedCheckBox.Value
                app.distance = data_gps(:,12)*1000; %en m
                app.Lap = data_gps(:,6); %nombre de tour effectué
                L = data_gps(:,7)*60; %temps du tour précédent en s
                app.Sector = data_gps(:,8); %secteur dans lequel se trouve la voiture
                app.SectorTime = data_gps(:,9); %temps pour le secteur précédent ?
                app.ElapsedTime = data_gps(:,10); %temps écoulé depuis le début du tour
                app.CurrentLap = data_gps(:,11); %numéro du tour en cours
            end
            
            app.AccelX_GPS = -interp1(app.Tacc,app.AccelX,app.Tgps); %en G avec un moins car le boitier était à l'envers
            app.AccelY_GPS = interp1(app.Tacc,app.AccelY,app.Tgps); %en G
            app.AccelZ_GPS = interp1(app.Tacc,app.AccelZ,app.Tgps); %en G
            app.Yaw_GPS = interp1(app.Tacc,app.Yaw,app.Tgps); %en deg/s
            app.Pitch_GPS = interp1(app.Tacc,app.Pitch,app.Tgps); %en deg/s
            app.Roll_GPS = interp1(app.Tacc,app.Roll,app.Tgps); %en deg/s
            %CAN
            app.RPM = data50(:,an+10); %en tour/min
            app.TPS = data50(:,an+11); 
            app.Twater = data50(:,an+12);
            app.Tair = data50(:,an+13);
            app.Pair = data50(:,an+14)/100; % en bar
            app.Lambda = data50(:,an+15)/1000; %unité classique (le can mulitiplie par 1000)
            app.Poil = data50(:,an+17)/100;
            app.Vbat = data50(:,an+20)/10; %en V
            app.WFL = data50(:,an+31); 
            app.WFR = data50(:,an+29); 
            app.WRL = data50(:,an+30); 
            app.WRR = data50(:,an+28);
            app.Gear = data50(:,an+22);
            app.Pfuel = data50(:,an+18)/100; %en bar
            
            %Analogique pour Optimus
            if an==2 
                app.BPF = data50(:,3);  %Pression en bars voir l'excel calib
                app.Steering = Fsteer(app,data50(:,4));
            end
            
            if an==6
                app.BPF = data50(:,3);  %Pression en bars voir l'excel calib
                volt = data50(:,4);
                assignin("base",'Test',volt);
                app.Steering = Fsteer(app,volt);
                p = [1.6 0.1];
                app.XFR = polyval(p,data50(:,5)); %Déplacement du capteur en mm
                app.XRR = polyval(p,data50(:,6));
                app.XFL = polyval(p,data50(:,7));
                app.XRL = polyval(p,data50(:,8));
            end
     
        
            % Clear temporary variables
            clearvars data50 data_gps raw A B
            
            %file informations
            if app.LaptimeloggedCheckBox.Value
                for k=1:length(app.ElapsedTime)-1
                    if (app.ElapsedTime(k)< 0.002 ) && (app.ElapsedTime(k+1)>0.002)
                        app.TLap = [app.TLap app.Tgps(k)];
                    end
                end
            end         
            
            %Duree
            s = mod(round(max(app.Time)),60); %secondes
            m = ( round(max(app.Time))-mod(round(max(app.Time)),60))/60; %minutes
            app.Duree.Text = strcat('Run duration  : ',' ',num2str(m),' min ',num2str(s),' s');
            if app.LaptimeloggedCheckBox.Value
            %Nb de tour
                nb = app.Lap(end);
                app.NbTour.Text = strcat('Number of lap : ',' ',num2str(nb));
                %meileur temps au tour
                 i=10;
                while length(app.LapTime)<nb
                     if app.Lap(i) == length(app.LapTime)+1
                         app.LapTime(length(app.LapTime)+1) = L(i);
                     end
                     i = i + 10;
                 end
                app.Ttour.Text = strcat('Best lap time ',' : ',num2str(min(app.LapTime)),' s'); 
            else
                app.NbTour.Text = strcat('pas dispo');
                app.Ttour.Text = strcat('pas dispo');
            end
            
            %Vmax
            app.Vmax.Text = strcat('Maximum speed : ',' ',num2str(round(max(app.Speed))),' km/h');
            %Gx max
            app.Gxmax.Text = strcat('Maximum longitudinal acceleration :',' ',num2str(max(app.AccelX)),' g');   
            %Gx min
            app.Gxmin.Text = strcat('Minimum longitudinal acceleration :',' ',num2str(min(app.AccelX)),' g');
            %Gy max
            app.Gymax.Text = strcat('Maximum lateral acceleration :',' ',num2str(max(abs(app.AccelY))),' g');
            
            [app.X, app.Y] = lat_longi2X_Y(app, app.Latitude, app.Longitude);
            track_plot(app, app.X, app.Y, app.Speed)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.Plot_app)
            delete(app.G_force)
            delete(app.appCornering)
            delete(app.appVital)
            delete(app.appBraking)
            delete(app.appTemps)
            delete(app.appGrip)
            delete(app)
        end

        % Menu selected function: tempsdistanceMenu
        function tempsdistanceMenuSelected(app, event)
            app.Plot_app = Plot_Temps_Dist(app);
        end

        % Menu selected function: GForces
        function GForcesMenuSelected(app, event)
            app.G_force = Gforce(app);
        end

        % Menu selected function: CorneringMenu
        function CorneringMenuSelected(app, event)
            app.appCornering = Cornering(app);
        end

        % Button pushed function: ExportdatatotheworkspaceButton
        function ExportdatatotheworkspaceButtonPushed(app, event)
            assignin('base',"Time",app.Time);
            assignin('base',"Tacc",app.Tacc);
            assignin('base',"Tgps",app.Tgps);
            assignin('base',"X",app.X);
            assignin('base',"Y",app.Y);
            assignin('base',"AccelX",app.AccelX);
            assignin('base',"AccelY",app.AccelY);
            assignin('base',"AccelZ",app.AccelZ);
            assignin('base',"Yaw",app.Yaw);
            assignin('base',"Pitch",app.Pitch);
            assignin('base',"Roll",app.Roll);
            assignin('base',"AccelX_GPS",app.AccelX_GPS);
            assignin('base',"AccelY_GPS",app.AccelY_GPS);
            assignin('base',"AccelZ_GPS",app.AccelZ_GPS);
            assignin('base',"Yaw_GPS",app.Yaw_GPS);
            assignin('base',"Pitch_GPS",app.Pitch_GPS);
            assignin('base',"Roll_GPS",app.Roll_GPS);
            assignin('base',"Latitude",app.Latitude);
            assignin('base',"Longitude",app.Longitude);
            assignin('base',"Speed",app.Speed);
            assignin('base',"distance",app.distance);
            assignin('base',"Altitude",app.Altitude);
            assignin('base',"Steering",app.Steering);
            assignin('base',"XFL",app.XFL);
            assignin('base',"XFR",app.XFR);
            assignin('base',"XRL",app.XRL);
            assignin('base',"XRR",app.XRR);
            assignin('base',"WFL",app.WFL);
            assignin('base',"WFR",app.WFR);
            assignin('base',"WRL",app.WRL);
            assignin('base',"WRR",app.WRR);
            assignin('base',"BPF",app.BPF);
            assignin('base',"BPR",app.BPR);
            assignin('base',"RPM",app.RPM);
            assignin('base',"Gear",app.Gear);
            assignin('base',"Pair",app.Pair);
            assignin('base',"Tair",app.Tair);
            assignin('base',"Twater",app.Twater);
            assignin('base',"Poil",app.Poil);
            assignin('base',"Pfuel",app.Pfuel);
            assignin('base',"Lambda",app.Lambda);
            assignin('base',"TPS",app.TPS);
            assignin('base',"Vbat",app.Vbat);
            assignin('base',"Lap",app.Lap);
            assignin('base',"LapTime",app.LapTime);
            assignin('base',"Sector",app.Sector);
            assignin('base',"SectorTime",app.SectorTime);
            assignin('base',"ElapsedTime",app.ElapsedTime);
            assignin('base',"CurrentLap",app.CurrentLap);
            assignin('base',"TLap",app.TLap); 
        end

        % Menu selected function: VitalFunctionsMenu
        function VitalFunctionsMenuSelected(app, event)
            app.appVital = Vital(app);
        end

        % Menu selected function: BrakingMenu
        function BrakingMenuSelected(app, event)
            app.appBraking = Braking(app);
        end

        % Menu selected function: TempsMenu
        function TempsMenuSelected(app, event)
            app.appTemps = Temps(app);
        end

        % Menu selected function: GripfactorsMenu
        function GripfactorsMenuSelected(app, event)
            app.appGrip = GripFactor(app);
        end

        % Button pushed function: Importa2ndrunButton
        function Importa2ndrunButtonPushed(app, event)
            app.DropDown.Visible = 'on';
            app.Info.Visible = 'on';

            
            % Number of analog input
            an = str2double(app.anag.Value);

            % Import the data en remplacant les vides par des NaN
            raw  = dlmread_empty(strcat(app.logfilename.Value, '.log'),',',1,0,NaN);
            
            % Sélection des données enregistrée à 50 Hz
            data50 = []; % données enregistrée à 50Hz
            for i= 1:length(raw)
                if abs(raw(i,an+3)) >= 0 % On ne garde que les moments ou Les G sont pris
                    data50 = [data50; raw(i,1:33)];
                end
            end

            % GPS data selection
            % data_gps = [time:1 lattitude:2 longitude:3 speed:4 height:5 distance:6 Ax:7 Ay:8 Az:9 Yaw:10 Pitch:11 Roll:12]
            data_gps = []; 
            indic = 1;
            for i=1:length(raw) %pour toutes les données
                if abs(raw(i,an+31)) > 0   %Si le GPS ne s'initialise plus
                    if raw(i,1) == data50(indic,1) % si le temps d'échantillionage du GPS correspond à celui des Accels
                      A = data50(indic,an+3:an+8); % Les accels
                   else
                     while raw(i,1) > data50(indic,1) % && data_raw(i,1) > data50(indic+1,1) %temps que le temps du gps dépasse celui des accels
                        indic = indic + 1;
                     end
                     a = (data50(indic,an+3:an+8) - data50(indic-1,an+3:an+8))./(data50(indic,1) - data50(indic-1,1));
                     b = data50(indic,an+3:an+8) - a*data50(indic,1);
                     A = a*raw(i,1) + b;
                    end
                    GPSi = [raw(i,1) raw(i, an+31:an+41) A];
                    data_gps = [data_gps; GPSi];
                end
            end
            
            % data50 = [time(ms):1  Ax(g):9  Ay(g):10  Az(g):11  Yaw(°/s):12  Pitch(°/s):13  Roll(°/s):14]
            
            
            % Allocate imported array to column variable names
            app.Time2 = (raw(:,1)-raw(1,1))/1000; % enregistrement de Time en s
            %Accels
            app.Tacc2 = (data50(:,1)-data50(1,1))/1000; %en seconde et remis à zeros
            app.AccelX2 = -data50(:,an+3); %en G avec un moins car le boitier était à l'envers
            app.AccelY2 = data50(:,an+4); %en G
            app.AccelZ2 = data50(:,an+5); %en G
            app.Yaw2 = data50(:,an+6); %en deg/s
            app.Pitch2 = data50(:,an+7); %en deg/s
            app.Roll2 = data50(:,an+8); %en deg/s
            %Gps
            app.Tgps2 = (data_gps(:,1)-data50(1,1))/1000; %en seconde et remis au niveau des accels
            app.Latitude2 = data_gps(:,2);
            app.Longitude2 = data_gps(:,3);
            app.Speed2 = data_gps(:,4)*1.60934; %en km/h
            app.Altitude2 = data_gps(:,5)*0.3048; % en m
            app.distance2 = data_gps(:,6)*1609.34; %en m
            app.Lap2 = data_gps(:,7); %nombre de tour effectué
            L2 = data_gps(:,8)*60; %temps du tour précédent en s
            app.Sector2 = data_gps(:,9); %secteur dans lequel se trouve la voiture
            app.SectorTime2 = data_gps(:,10); %temps pour le secteur précédent ?
            app.ElapsedTime2 = data_gps(:,11); %temps écoulé depuis le début du tour
            app.CurrentLap2 = data_gps(:,12); %numéro du tour en cours
            
            app.AccelX_GPS2 = -data_gps(:,13); %en G avec un moins car le boitier était à l'envers
            app.AccelY_GPS2 = data_gps(:,14); %en G
            app.AccelZ_GPS2 = data_gps(:,15); %en G
            app.Yaw_GPS2 = data_gps(:,16); %en deg/s
            app.Pitch_GPS2 = data_gps(:,17); %en deg/s
            app.Roll_GPS2 = data_gps(:,18); %en deg/s
            %CAN
            app.RPM2 = data50(:,an+9); %en tour/min
            app.TPS2 = data50(:,an+10); 
            app.Twater2 = data50(:,an+11);
            app.Tair2 = data50(:,an+12);
            app.Pair2 = data50(:,an+13)/100; % en bar
            app.Lambda2 = data50(:,an+14)/1000; %unité classique (le can mulitiplie par 1000)
            app.Poil2 = data50(:,an+16);
            app.Vbat2 = data50(:,an+19)/10; %en V
            app.WFL2 = data50(:,an+30); 
            app.WFR2 = data50(:,an+28); 
            app.WRL2 = data50(:,an+29); 
            app.WRR2 = data50(:,an+27);
            app.Gear2 = data50(:,an+21);

            % Clear temporary variables
            clearvars data50 data_gps raw
            
            %file informations
            for k=1:length(app.ElapsedTime)-1
                if (app.ElapsedTime2(k)< 0.002 ) && (app.ElapsedTime2(k+1)>0.002)
                    app.TLap2 = [app.TLap2 app.Tgps2(k)];
                end
            end
                        
            
            %Duree
            s = mod(round(max(app.Time2)),60);
            m = ( round(max(app.Time2))-mod(round(max(app.Time2)),60))/60;
            app.Duree.Text = strcat('Run duration  : ',' ',num2str(m),' min ',num2str(s),' s');  
            %Nb de tour
            nb = app.Lap2(end);
            app.NbTour.Text = strcat('Number of lap : ',' ',num2str(nb));
            %meileur temps au tour
            i=10;
            while length(app.LapTime2)<nb
                if app.Lap2(i) == length(app.LapTime2)+1
                    app.LapTime2(length(app.LapTime2)+1) = L2(i);
                end
                i = i + 10;
            end
            app.Ttour.Text = strcat('Best lap time ',' : ',num2str(min(app.LapTime2)),' s');      
            %Vmax
            app.Vmax.Text = strcat('Maximum speed : ',' ',num2str(round(max(app.Speed2))),' km/h');
            %Gx max
            app.Gxmax.Text = strcat('Maximum longitudinal acceleration :',' ',num2str(max(app.AccelX2)),' g');   
            %Gx min
            app.Gxmin.Text = strcat('Minimum longitudinal acceleration :',' ',num2str(min(app.AccelX2)),' g');
            %Gy max
            app.Gymax.Text = strcat('Maximum lateral acceleration :',' ',num2str(max(abs(app.AccelY2))),' g');
            
            [app.X2, app.Y2] = lat_longi2X_Y(app, app.Latitude2, app.Longitude2);
            track_plot(app, app.X2, app.Y2, app.Speed2)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == '1'
                %Duree
                s = mod(round(max(app.Time)),60);
                m = ( round(max(app.Time))-mod(round(max(app.Time)),60))/60;
                app.Duree.Text = strcat('Run duration  : ',' ',num2str(m),' min ',num2str(s),' s');  
                %Nb de tour
                nb = app.Lap(end);
                app.NbTour.Text = strcat('Number of lap : ',' ',num2str(nb));
                %meileur temps au tour
                app.Ttour.Text = strcat('Best lap time ',' : ',num2str(min(app.LapTime)),' s');      
                %Vmax
                app.Vmax.Text = strcat('Maximum speed : ',' ',num2str(round(max(app.Speed))),' km/h');
                %Gx max
                app.Gxmax.Text = strcat('Maximum longitudinal acceleration :',' ',num2str(max(app.AccelX)),' g');   
                %Gx min
                app.Gxmin.Text = strcat('Minimum longitudinal acceleration :',' ',num2str(min(app.AccelX)),' g');
                %Gy max
                app.Gymax.Text = strcat('Maximum lateral acceleration :',' ',num2str(max(abs(app.AccelY))),' g');
                
                [app.X, app.Y] = lat_longi2X_Y(app, app.Latitude, app.Longitude);
                track_plot(app, app.X, app.Y, app.Speed)

            else
                %Duree
                s = mod(round(max(app.Time2)),60);
                m = ( round(max(app.Time2))-mod(round(max(app.Time2)),60))/60;
                app.Duree.Text = strcat('Run duration  : ',' ',num2str(m),' min ',num2str(s),' s');  
                %Nb de tour
                nb = app.Lap2(end);
                app.NbTour.Text = strcat('Number of lap : ',' ',num2str(nb));
                %meileur temps au tour
                app.Ttour.Text = strcat('Best lap time ',' : ',num2str(min(app.LapTime2)),' s');      
                %Vmax
                app.Vmax.Text = strcat('Maximum speed : ',' ',num2str(round(max(app.Speed2))),' km/h');
                %Gx max
                app.Gxmax.Text = strcat('Maximum longitudinal acceleration :',' ',num2str(max(app.AccelX2)),' g');   
                %Gx min
                app.Gxmin.Text = strcat('Minimum longitudinal acceleration :',' ',num2str(min(app.AccelX2)),' g');
                %Gy max
                app.Gymax.Text = strcat('Maximum lateral acceleration :',' ',num2str(max(abs(app.AccelY2))),' g');
                
                [app.X2, app.Y2] = lat_longi2X_Y(app, app.Latitude2, app.Longitude2);
                track_plot(app, app.X2, app.Y2, app.Speed2)
            end
        end

        % Menu selected function: DampersMenu
        function DampersMenuSelected(app, event)
            app.appDampers = Dampers(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DisplayMenu = uimenu(app.UIFigure);
            app.DisplayMenu.Text = 'Display';
            app.tempsdistanceMenu = uimenu(app.DisplayMenu);
            app.tempsdistanceMenu.MenuSelectedFcn = createCallbackFcn(app, @tempsdistanceMenuSelected, true);
            app.tempsdistanceMenu.Text = '/temps,distance';
            app.AnalysisMenu = uimenu(app.UIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.GForces = uimenu(app.AnalysisMenu);
            app.GForces.MenuSelectedFcn = createCallbackFcn(app, @GForcesMenuSelected, true);
            app.GForces.Text = 'G-Forces';
            app.CorneringMenu = uimenu(app.AnalysisMenu);
            app.CorneringMenu.MenuSelectedFcn = createCallbackFcn(app, @CorneringMenuSelected, true);
            app.CorneringMenu.Text = 'Cornering';
            app.VitalFunctionsMenu = uimenu(app.AnalysisMenu);
            app.VitalFunctionsMenu.MenuSelectedFcn = createCallbackFcn(app, @VitalFunctionsMenuSelected, true);
            app.VitalFunctionsMenu.Text = 'Vital Functions';
            app.BrakingMenu = uimenu(app.AnalysisMenu);
            app.BrakingMenu.MenuSelectedFcn = createCallbackFcn(app, @BrakingMenuSelected, true);
            app.BrakingMenu.Text = 'Braking';
            app.GripfactorsMenu = uimenu(app.AnalysisMenu);
            app.GripfactorsMenu.MenuSelectedFcn = createCallbackFcn(app, @GripfactorsMenuSelected, true);
            app.GripfactorsMenu.Text = 'Grip factors';
            app.DampersMenu = uimenu(app.AnalysisMenu);
            app.DampersMenu.MenuSelectedFcn = createCallbackFcn(app, @DampersMenuSelected, true);
            app.DampersMenu.Text = 'Dampers';
            app.PiloteMenu = uimenu(app.UIFigure);
            app.PiloteMenu.Text = 'Pilote';
            app.TempsMenu = uimenu(app.PiloteMenu);
            app.TempsMenu.MenuSelectedFcn = createCallbackFcn(app, @TempsMenuSelected, true);
            app.TempsMenu.Text = 'Temps';
            app.GPS = uiaxes(app.UIFigure);
            title(app.GPS, 'Coordonnées GPS')
            app.NameofthelogfileLabel = uilabel(app.UIFigure);
            app.NameofthelogfileLabel.Text = 'Name of the log file';
            app.logfilename = uieditfield(app.UIFigure, 'text');
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.Duree = uilabel(app.UIFigure);
            app.Duree.Text = 'Run duration : ';
            app.Vmax = uilabel(app.UIFigure);
            app.Vmax.Text = 'Maximuml speed :';
            app.Gxmax = uilabel(app.UIFigure);
            app.Gxmax.Text = 'Maximum longitudinal acceleration :';
            app.Gxmin = uilabel(app.UIFigure);
            app.Gxmin.Text = 'Minimum longitudinale acceleration :';
            app.Gymax = uilabel(app.UIFigure);
            app.Gymax.Text = 'Maximum lateral acceleration :';
            app.logLabel = uilabel(app.UIFigure);
            app.logLabel.Text = '.log';
            app.ExportdatatotheworkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExportdatatotheworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdatatotheworkspaceButtonPushed, true);
            app.ExportdatatotheworkspaceButton.Text = 'Export data to the workspace';
            app.Ttour = uilabel(app.UIFigure);
            app.Ttour.Text = 'Best lap time :';
            app.NbTour = uilabel(app.UIFigure);
            app.NbTour.Text = 'Number of lap :';
            app.NumberofanaloginputsDropDownLabel = uilabel(app.UIFigure);
            app.NumberofanaloginputsDropDownLabel.Text = 'Number of analog inputs :';
            app.anag = uidropdown(app.UIFigure);
            app.anag.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8'};
            app.anag.Value = '6';
            app.Importa2ndrunButton = uibutton(app.UIFigure, 'push');
            app.Importa2ndrunButton.ButtonPushedFcn = createCallbackFcn(app, @Importa2ndrunButtonPushed, true);
            app.Importa2ndrunButton.Text = 'Import a 2nd run';
            app.Info = uilabel(app.UIFigure);
            app.Info.Text = 'Infos of the run number :';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'1', '2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1';
            app.LaptimeloggedCheckBox = uicheckbox(app.UIFigure);
            app.LaptimeloggedCheckBox.Text = 'Lap time logged';
            app.LaptimeloggedCheckBox.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = datavisu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end