classdef Braking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Eq                 matlab.ui.control.Label
        GPSCheck           matlab.ui.control.CheckBox
        FinLabel           matlab.ui.control.Label
        DebutLabel         matlab.ui.control.Label
        Sliderfin          matlab.ui.control.Slider
        Sliderdebut        matlab.ui.control.Slider
        Slidergps          matlab.ui.control.Slider
        BrakeBalanceCheck  matlab.ui.control.CheckBox
        FrontBPCheck       matlab.ui.control.CheckBox
        RearBPCheck        matlab.ui.control.CheckBox
        GxCheck            matlab.ui.control.CheckBox
        SpeedCheck         matlab.ui.control.CheckBox
        plotDropDown       matlab.ui.control.DropDown
        plotDropDownLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        BPR %bar, pression frein arrière
        BPF %bar, pression frein avant
        Bias % pourcentage de frein avant
        appGPS %app GPS
        CallingApp %app datavisu
        t % booléen pour savoir si on trace enfonction du temps
        L % liste pour savoir ce que l'on trace 
        Tbrake % liste des temps des pressions frein
        Taccel % liste des temps des accels
        Tspeed % liste des temps vitesse
        Speed
        AccelX
    end

    methods (Access = private)
        function i = findi(~, L, val)
            %Cette fonction renvoie le premier indice auquel se trouve une valeur de la liste L supérieur à val
            i=0;
            k=1;
            while (i==0) && (k<=length(L))
                if L(k)>=val
                    i=k;
                end
                k=k+1;
            end
            if i==0
                i=length(L);
            end
        end
        function Majplot(app,Valuegps)
            hold(app.UIAxes,'off');
            grid(app.UIAxes,'on')
            if app.L(1)
                plot(app.UIAxes, app.Tspeed,app.Speed,'DisplayName','speed (km/h)');
                hold(app.UIAxes,'on');
            end
            if app.L(2)
                plot(app.UIAxes, app.Taccel, app.AccelX,'DisplayName','Longitudinal G');
                hold(app.UIAxes,'on');
            end
            if app.L(3)
                plot(app.UIAxes, app.Tbrake, app.BPR,'DisplayName','Rear brake pressure (bar)');
                hold(app.UIAxes,'on');
            end
            if app.L(4)
                plot(app.UIAxes, app.Tbrake, app.BPF,'DisplayName','Front brake pressure (bar)');
                hold(app.UIAxes,'on');
            end
            if app.L(5)
                plot(app.UIAxes, app.Tbrake, app.Bias,'DisplayName','Bias brake pressure');
                hold(app.UIAxes,'on');
            end
            if app.GPSCheck.Value   % tracé du GPS
                if app.t
                    i = findi(app,app.Tspeed,Valuegps);
                    plot(app.UIAxes,app.Tspeed(i),0, 'o','MarkerFaceColor','r');
                else
                    i = findi(app,app.Distance,Valuegps);
                    plot(app.UIAxes,app.Distance(i),0, 'o','MarkerFaceColor','r');
                end
            end
            legend(app.UIAxes,'show')
            hold(app.UIAxes,'off');
        end
            
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            app.GxCheck.Visible='off';
            app.SpeedCheck.Visible='off';
            app.RearBPCheck.Visible='off';
            app.FrontBPCheck.Visible='off';
            app.Slidergps.Visible='off';
            app.Sliderdebut.Visible='off';
            app.Sliderfin.Visible='off';
            app.BrakeBalanceCheck.Visible = 'off';
            app.GPSCheck.Visible = 'off';
            
            app.BPF = get_data(app.CallingApp.data_struct,"Brake_F");
            app.BPR = get_data(app.CallingApp.data_struct,"Brake_R");
            app.Speed = get_data(app.CallingApp.data_struct,"Speed");
            app.AccelX = get_data(app.CallingApp.data_struct,"AccelX");
            for i=1:length(app.BPF)
                app.Bias(i)=app.BPF(i)/(app.BPF(i) + app.BPR(i));
            end
            
            
            index_brake = get_data_index(app.CallingApp.data_struct, "Brake_R");
            index_accel = get_data_index(app.CallingApp.data_struct, "AccelX");
            index_speed = get_data_index(app.CallingApp.data_struct, "Speed");
            
            P_brake = strcat('F',num2str(app.CallingApp.data_struct(index_brake).Frequency));
            P_accel = strcat('F',num2str(app.CallingApp.data_struct(index_accel).Frequency));
            P_speed = strcat('F',num2str(app.CallingApp.data_struct(index_speed).Frequency));
            
            
            app.Tbrake = get_data(app.CallingApp.data_struct, P_brake);
            app.Taccel = get_data(app.CallingApp.data_struct, P_accel);
            app.Tspeed = get_data(app.CallingApp.data_struct, P_speed);
        end

        % Value changed function: plotDropDown
        function plotDropDownValueChanged(app, event)
            value = app.plotDropDown.Value;
            
            if strcmp(value,'Rear Pressure / Front Pressure')
                delete(app.appGPS);
                app.Eq.Visible = 'on';
                app.GxCheck.Visible='off';
                app.SpeedCheck.Visible='off';
                app.RearBPCheck.Visible='off';
                app.FrontBPCheck.Visible='off';
                app.Slidergps.Visible='off';
                app.Sliderdebut.Visible='off';
                app.Sliderfin.Visible='off';
                app.DebutLabel.Visible = 'off';
                app.FinLabel.Visible = 'off';
                app.BrakeBalanceCheck.Visible = 'off';
                app.GPSCheck.Visible = 'off';
                plot(app.UIAxes, app.BPF, app.BPR, '*');
                hold(app.UIAxes,"on")
                xlabel(app.UIAxes,'Front Brake Pressure');
                ylabel(app.UIAxes,'Rear Brake Pressure');
                p = polyfit(app.BPF, app.BPR,1);
                plot(app.UIAxes, app.BPF, app.BPF.*p(1)+p(2));
                app.Eq.Text = ['Répartition des pressions de freinage : BPR = ',num2str(p(1),2),'*BPF + ',num2str(p(2),2)];
            elseif strcmp(value,'Bias / Front Pressure')
                delete(app.appGPS);
                app.DebutLabel.Visible = 'off';
                app.FinLabel.Visible = 'off';
                app.GxCheck.Visible='off';
                app.SpeedCheck.Visible='off';
                app.RearBPCheck.Visible='off';
                app.FrontBPCheck.Visible='off';
                app.Slidergps.Visible='off';
                app.Sliderdebut.Visible='off';
                app.Sliderfin.Visible='off';
                app.BrakeBalanceCheck.Visible = 'off';
                app.GPSCheck.Visible = 'off';
                app.Eq.Visible = 'off';
                hold(app.UIAxes,"off")
                plot(app.UIAxes, app.BPF, app.Bias, '*');
                xlabel(app.UIAxes,'Front Brake Pressure');
                ylabel(app.UIAxes,'Brake Balance');
            elseif strcmp(value,'data / time')
                app.t = 1;
                app.L =[0 0 0 0 0];
                delete(app.appGPS);
                app.GxCheck.Visible='on';
                app.GxCheck.Value = 0;
                app.SpeedCheck.Visible='on';
                app.SpeedCheck.Value = 0;
                app.RearBPCheck.Visible='on';
                app.RearBPCheck.Value = 0;
                app.FrontBPCheck.Visible='on';
                app.FrontBPCheck.Value = 0;
                app.BrakeBalanceCheck.Visible = 'on';
                app.BrakeBalanceCheck.Value = 0;
                app.GPSCheck.Visible = 'on';
                app.GPSCheck.Value = 0;
                %app.Slidergps.Limits = [0 app.CallingApp.Time(end)];
                app.Slidergps.Visible='off';
                app.Slidergps.Value = 0;
                %app.Sliderdebut.Limits = [0 app.CallingApp.Time(end)];
                app.Sliderdebut.Visible='off';
                app.Sliderdebut.Value = 0;
                %app.Sliderfin.Limits = [0 app.CallingApp.Time(end)];
                app.Sliderfin.Visible='off';
                %app.Sliderfin.Value = app.CallingApp.Time(end);
                
                xlabel(app.UIAxes,'time (s)');
                ylabel(app.UIAxes,'');
                Majplot(app,0);
            end
        end

        % Value changed function: GxCheck
        function GxCheckValueChanged(app, event)
            value = app.GxCheck.Value;
            if value == 1
                app.L(2) = 1;
            else
                app.L(2) = 0;
            end 
            Majplot(app,app.Slidergps.Value);
        end

        % Value changed function: SpeedCheck
        function SpeedCheckValueChanged(app, event)
            value = app.SpeedCheck.Value;
            if value == 1
                app.L(1) = 1;
            else
                app.L(1) = 0;
            end 
            Majplot(app,app.Slidergps.Value);            
        end

        % Value changed function: RearBPCheck
        function RearBPCheckValueChanged(app, event)
            value = app.RearBPCheck.Value;
            if value == 1
                app.L(3) = 1;
            else
                app.L(3) = 0;
            end 
            Majplot(app,app.Slidergps.Value);            
        end

        % Value changed function: FrontBPCheck
        function FrontBPCheckValueChanged(app, event)
            value = app.FrontBPCheck.Value;
            if value == 1
                app.L(4) = 1;
            else
                app.L(4) = 0;
            end 
            Majplot(app,app.Slidergps.Value);
        end

        % Value changed function: BrakeBalanceCheck
        function BrakeBalanceCheckValueChanged(app, event)
            value = app.BrakeBalanceCheck.Value;
            if value == 1
                app.L(5) = 1;
            else
                app.L(5) = 0;
            end 
            Majplot(app,app.Slidergps.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.plotDropDownLabel = uilabel(app.UIFigure);
            app.plotDropDownLabel.Text = 'plot :';
            app.plotDropDown = uidropdown(app.UIFigure);
            app.plotDropDown.Items = {'...', 'Rear Pressure / Front Pressure', 'Bias / Front Pressure', 'data / time'};
            app.plotDropDown.ValueChangedFcn = createCallbackFcn(app, @plotDropDownValueChanged, true);
            app.plotDropDown.Value = '...';
            app.SpeedCheck = uicheckbox(app.UIFigure);
            app.SpeedCheck.ValueChangedFcn = createCallbackFcn(app, @SpeedCheckValueChanged, true);
            app.SpeedCheck.Text = 'Speed';
            app.GxCheck = uicheckbox(app.UIFigure);
            app.GxCheck.ValueChangedFcn = createCallbackFcn(app, @GxCheckValueChanged, true);
            app.GxCheck.Text = 'Gx';
            app.RearBPCheck = uicheckbox(app.UIFigure);
            app.RearBPCheck.ValueChangedFcn = createCallbackFcn(app, @RearBPCheckValueChanged, true);
            app.RearBPCheck.Text = 'Rear BP';
            app.FrontBPCheck = uicheckbox(app.UIFigure);
            app.FrontBPCheck.ValueChangedFcn = createCallbackFcn(app, @FrontBPCheckValueChanged, true);
            app.FrontBPCheck.Text = 'Front BP';
            app.BrakeBalanceCheck = uicheckbox(app.UIFigure);
            app.BrakeBalanceCheck.ValueChangedFcn = createCallbackFcn(app, @BrakeBalanceCheckValueChanged, true);
            app.BrakeBalanceCheck.Text = 'Brake Balance';
            app.Slidergps = uislider(app.UIFigure);
            app.Sliderdebut = uislider(app.UIFigure);
            app.Sliderfin = uislider(app.UIFigure);
            app.DebutLabel = uilabel(app.UIFigure);
            app.DebutLabel.Text = 'Début';
            app.FinLabel = uilabel(app.UIFigure);
            app.FinLabel.Text = 'Fin';
            app.GPSCheck = uicheckbox(app.UIFigure);
            app.GPSCheck.Text = 'GPS';
            app.Eq = uilabel(app.UIFigure);
            app.Eq.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Braking(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end