classdef FuelC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        meaninj                         matlab.ui.control.Label
        MeaninjectionTimeLabel          matlab.ui.control.Label
        DistanceTot                     matlab.ui.control.Label
        TotaldistanceforthisrunLabel    matlab.ui.control.Label
        mLsLabel                        matlab.ui.control.Label
        InjectorflowEditField           matlab.ui.control.NumericEditField
        InjectorflowEditField_2Label    matlab.ui.control.Label
        Conso100                        matlab.ui.control.Label
        ConsoTot                        matlab.ui.control.Label
        Averagecomsuptionfor100kmLabel  matlab.ui.control.Label
        TotalcomsuptionforthisrunLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data_struct 
        CallingApp
    end
    
    methods (Access = private)
        
         function [] = track_plot(app, X, Y, T)
            % X et Y sont des coordonnées venant de lat_longi2X_Y
            % speed est en km/h
            cla(app.UIAxes,'reset') % reset le graph
            z = zeros(size(X'));
            col = T';  % This is the color, vary with x in this case.
            surface(app.UIAxes, [X';X'],[Y';Y'],[z;z],[col;col],'facecol','no','edgecol','flat','linew',2);
            c = colorbar(app.UIAxes);
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            c.Label.String = 'Fuel consumption (log( L/100km ))';
            axis(app.UIAxes,[min(X)-10,max(X)+10,min(Y)-10,max(Y)+10]);
            set(app.UIAxes,'Visible','off')
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            app.data_struct = app.CallingApp.data_struct;
            app.InjectorflowEditField.Value = 3.9;
            Injindex = get_data_index(app.data_struct,'Injection');
            Injdata = app.data_struct(Injindex).Data;
           
            Distance = get_data(app.CallingApp.data_struct, "Distance");
            for i=length(Distance)-1:-1:1
                if Distance(i+1)==0 && Distance(i)~= 0
                    Distance(i+1:end) = Distance(i+1:end) + Distance(i);
                end
            end
            %
            id1 = get_data_index(app.data_struct,"ConsoKm");
            id2 = get_data_index(app.data_struct,"Distance");
            P1 = strcat('F',num2str(app.data_struct(id1).Frequency)); %le nom des temps pour les bonnes fréquences 
            P2 = strcat('F',num2str(app.data_struct(id2).Frequency));
            if ~strcmp(P1,P2)
                idP1 = strcmp({app.data_struct.Name}, P1)==1; %les listes des temps pour les bonnes fréquences 
                idP2 = strcmp({app.data_struct.Name}, P2)==1;
                Conso_interp = interp1(app.data_struct(idP1).Data,app.data_struct(id1).Data,app.data_struct(idP2).Data);
            else
                Conso_interp = app.data_struct(id1).Data;
            end

            Total_conso = cumtrapz(Distance/1000,Conso_interp/100); % /100 pour ramener au L/km et /1000 pour mettre la distance en km
            app.ConsoTot.Text = {num2str(Total_conso(end),3),' L'};
            consotot = Total_conso(end);
            %
            distance_max = max(Distance);
            app.Conso100.Text = cat(2,num2str(consotot/distance_max*10^5,3),' L/100km');
            app.DistanceTot.Text = cat(2,num2str(distance_max),' m');
            app.meaninj.Text = cat(2,num2str(mean(Injdata),2),' ms');            
            Xindex = get_data_index(app.data_struct, 'X');
            X = get_data(app.data_struct,'X');
            Y = get_data(app.data_struct,'Y');
            
            track_plot(app,X,Y,log(Conso_interp));
            %if app.data_struct(Xindex).Frequency == app.data_struct(Injindex).Frequency
            %    track_plot(app,X, Y, RealInjection);
            %else
            %    P1 = strcat('F',num2str(app.data_struct(Xindex).Frequency)); %le nom des temps pour les bonnes fréquences 
            %    P2 = strcat('F',num2str(app.data_struct(Injindex).Frequency));
            %    idP1 = strcmp({app.data_struct.Name}, P1)==1; %les listes des temps pour les bonnes fréquences 
            %    idP2 = strcmp({app.data_struct.Name}, P2)==1;
            %    
            %    RealInjection = interp1(app.data_struct(idP2).Data,RealInjection,app.data_struct(idP1).Data); %Interpolation
            %    track_plot(app,X, Y, RealInjection);
            %end
        end

        % Callback function
        function InjectorflowEditFieldValueChanged(app, event)
            debit_inj = app.InjectorflowEditField.Value;
            Injindex = get_data_index(app.data_struct, 'Injection');
            Injdata = app.data_struct(Injindex).Data;
            fe = app.data_struct(Injindex).Frequency;
            RPM = get_data(app.data_struct, 'RPM');
            [RealInjection,consotot] = model_Conso(Injdata,RPM,debit_inj,fe);
            app.ConsoTot.Text = cat(2,num2str(consotot,3),' L');
            distance_max = max(get_data(app.data_struct, 'Distance'));
            app.Conso100.Text = cat(2,num2str(consotot/distance_max*10^5,3),' L/100km');
            
            Xindex = get_data_index(app.data_struct, 'X');
            X = get_data(app.data_struct,'X');
            Y = get_data(app.data_struct,'Y');
            if app.data_struct(Xindex).Frequency == app.data_struct(Injindex).Frequency
                track_plot(app,X, Y, RealInjection);
            else
                P1 = strcat('F',num2str(app.data_struct(Xindex).Frequency)); %le nom des temps pour les bonnes fréquences 
                P2 = strcat('F',num2str(app.data_struct(Injindex).Frequency));
                idP1 = strcmp({app.data_struct.Name}, P1)==1; %les listes des temps pour les bonnes fréquences 
                idP2 = strcmp({app.data_struct.Name}, P2)==1;
                
                RealInjection = interp1(app.data_struct(idP2).Data,RealInjection,app.data_struct(idP1).Data); %Interpolation
                track_plot(app,X, Y, RealInjection);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Injection plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TotalcomsuptionforthisrunLabel = uilabel(app.UIFigure);
            app.TotalcomsuptionforthisrunLabel.Text = 'Total comsuption for this run : ';
            app.Averagecomsuptionfor100kmLabel = uilabel(app.UIFigure);
            app.Averagecomsuptionfor100kmLabel.Text = 'Average comsuption for 100km :';
            app.ConsoTot = uilabel(app.UIFigure);
            app.ConsoTot.Text = '';
            app.Conso100 = uilabel(app.UIFigure);
            app.Conso100.Text = '';
            app.InjectorflowEditField_2Label = uilabel(app.UIFigure);
            app.InjectorflowEditField_2Label.Text = 'Injector flow';
            app.InjectorflowEditField = uieditfield(app.UIFigure, 'numeric');
            app.mLsLabel = uilabel(app.UIFigure);
            app.mLsLabel.Text = 'mL/s';
            app.TotaldistanceforthisrunLabel = uilabel(app.UIFigure);
            app.TotaldistanceforthisrunLabel.Text = 'Total distance for this run : ';
            app.DistanceTot = uilabel(app.UIFigure);
            app.DistanceTot.Text = '';
            app.MeaninjectionTimeLabel = uilabel(app.UIFigure);
            app.MeaninjectionTimeLabel.Text = 'Mean injection Time :';
            app.meaninj = uilabel(app.UIFigure);
            app.meaninj.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FuelC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end