classdef Gforce < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure           matlab.ui.Figure
        GPSSlider        matlab.ui.control.Slider
        GPScheck         matlab.ui.control.CheckBox
        GcombCheck       matlab.ui.control.CheckBox
        Gycheck          matlab.ui.control.CheckBox
        Gxcheck          matlab.ui.control.CheckBox
        Choixduplot      matlab.ui.control.DropDown
        PlotchoiceLabel  matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        
        CallingApp % app qui à ouvert celle-ci
        Accelcomb % Norme de GX et GY
        Accelcomb_GPS % la même mais seulement pour les temps gps
        L %liste des cases cochées
        t % booléen qui définit si on trace en fonction du temps ou de la distance
        appGPS % Description
        
        AccelX;
        AccelX_GPS;
        
        AccelY;
        AccelY_GPS;
        
        Distance;
        Speed;
        
        T50;
        

    end

    methods (Access = private)
       function i = findi(~, L, val)
            %Cette fonction renvoie le premier indice auquel se trouve une valeur de la liste L supérieur à val
            i=0;
            k=1;
            while (i==0) && (k<=length(L))
                if L(k)>=val
                    i=k;
                end
                k=k+1;
            end
            if i==0
                i=length(L);
            end
       end
   
        function Majplot(app,Valuegps)
            hold(app.UIAxes,'off');
            
            if app.L(1)
                if app.t==1
                    plot(app.UIAxes, app.T50, app.AccelX,'DisplayName','Longitudinal G');
                else
                    plot(app.UIAxes, app.Distance, app.AccelX_GPS,'DisplayName','Longitudinal G');
                end
                hold(app.UIAxes,'on');
            end
            if app.L(2)
                if app.t==1
                    plot(app.UIAxes, app.T50, app.AccelY,'DisplayName','Lateral G');
                else
                    plot(app.UIAxes, app.Distance, app.AccelY_GPS,'DisplayName','Lateral G');
                end
                hold(app.UIAxes,'on');
            end
            if app.L(3)
                if app.t==1
                    plot(app.UIAxes, app.T50, app.Accelcomb,'DisplayName','Combined G');
                else
                    plot(app.UIAxes, app.Distance, app.Accelcomb_GPS,'DisplayName','Combined G');
                end
                hold(app.UIAxes,'on');
            end
            if app.GPScheck.Value   % tracé du GPS
                if app.t
                    i = findi(app,app.T50,Valuegps);
                    plot(app.UIAxes,app.T50(i),0, 'o','MarkerFaceColor','r');
                else
                    i = findi(app,app.Distance,Valuegps);
                    plot(app.UIAxes,app.Distance(i),0, 'o','MarkerFaceColor','r');
                end
            end
            legend(app.UIAxes,'show')
            hold(app.UIAxes,'off');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.figure.Name = 'G-Forces';
            app.CallingApp = Mainapp;
            delete(app.appGPS)
            app.GPScheck.Visible='off';
            app.GPSSlider.Visible='off';
            legend(app.UIAxes,'off')
            app.Gxcheck.Visible='off';
            app.Gycheck.Visible='off';
            app.GcombCheck.Visible='off';
            
            app.Distance = get_data(app.CallingApp.data_struct,'Distance');
            
            app.AccelX=get_data(app.CallingApp.data_struct, "AccelX");
            %app.AccelX_GPS=get_data(app.CallingApp.data_struct, "AccelX_GPS");
            
            app.AccelY=get_data(app.CallingApp.data_struct, "AccelY");
            %app.AccelY_GPS=get_data(app.CallingApp.data_struct, "AccelY_GPS");
            
            app.Speed=get_data(app.CallingApp.data_struct, "Speed");
            
            app.T50=freq2time(app.CallingApp.data_struct,50);
            
            hold(app.UIAxes,'off')
            b = max(abs(app.AccelX));
            a = max(abs(app.AccelY));
            x_ell = (-a:0.01:a);
            y_ell = b*sqrt(1 - (x_ell/a).^2);
            plot(app.UIAxes, app.AccelY, app.AccelX, '+');
            hold(app.UIAxes,'on')
            plot(app.UIAxes, x_ell, y_ell, 'r')
            plot(app.UIAxes, x_ell, -y_ell, 'r')
            hold(app.UIAxes,'off')
            xlabel(app.UIAxes,'Lateral G');
            ylabel(app.UIAxes,'Longitudinal G');
            
            %Conversion données IMU à la bonne fréquence
            Xindex = get_data_index(app.CallingApp.data_struct, 'X');
            index_yaw = get_data_index(app.CallingApp.data_struct, "AccelX");
            P1 = strcat('F',num2str(app.CallingApp.data_struct(Xindex).Frequency)); %le nom des temps pour les bonnes fréquences 
            P2 = strcat('F',num2str(app.CallingApp.data_struct(index_yaw).Frequency));
            idP1 = strcmp({app.CallingApp.data_struct.Name}, P1)==1; %les listes des temps pour les bonnes fréquences 
            idP2 = strcmp({app.CallingApp.data_struct.Name}, P2)==1;
            app.AccelX_GPS = interp1(app.CallingApp.data_struct(idP2).Data,app.AccelX,app.CallingApp.data_struct(idP1).Data);
            app.AccelY_GPS = interp1(app.CallingApp.data_struct(idP2).Data,app.AccelY,app.CallingApp.data_struct(idP1).Data);
            
            for i=1:length(app.AccelX_GPS)
                app.Accelcomb_GPS(i)=sqrt(app.AccelX_GPS(i)^2+app.AccelY_GPS(i)^2);
            end
            for i=1:length(app.AccelX)
                app.Accelcomb(i)=sqrt(app.AccelX(i)^2+app.AccelY(i)^2);
            end
        end

        % Value changed function: Choixduplot
        function ChoixduplotValueChanged(app, event)
            value = app.Choixduplot.Value;
            
            if strcmp(value,'Traction Circle')
                delete(app.appGPS)
                app.GPScheck.Visible='off';
                app.GPSSlider.Visible='off';
                legend(app.UIAxes,'off')
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                hold(app.UIAxes,'off')
                b = max(abs(app.AccelX));
                a = max(abs(app.AccelY));
                x_ell = (-a:0.01:a);
                y_ell = b*sqrt(1 - (x_ell/a).^2);
                plot(app.UIAxes, app.AccelY, app.AccelX, '+');
                hold(app.UIAxes,'on')
                plot(app.UIAxes, x_ell, y_ell, 'r')
                plot(app.UIAxes, x_ell, -y_ell, 'r')
                hold(app.UIAxes,'off')
                xlabel(app.UIAxes,'Lateral G');
                ylabel(app.UIAxes,'Longitudinal G');
                
            elseif strcmp(value,'Accels / Temps')
                app.t = 1;
                app.L=[0 0 0];
                app.Gxcheck.Visible='on';
                app.Gycheck.Visible='on';
                app.GcombCheck.Visible='on';
                app.GPScheck.Visible='on';
                app.GPSSlider.Visible='off';
                app.GPScheck.Value=0;
                app.Gxcheck.Value= 0;
                app.Gycheck.Value= 0;
                app.GcombCheck.Value= 0;
                plot(app.UIAxes,0,0);
                xlabel(app.UIAxes,'time(s)');
                ylabel(app.UIAxes,'');
            elseif strcmp(value,'Accels / Distance')
                app.t = 0;
                app.L=[0 0 0];
                app.Gxcheck.Visible='on';
                app.Gycheck.Visible='on';
                app.GcombCheck.Visible='on';
                app.GPScheck.Visible='on';
                app.GPSSlider.Visible='off';
                app.GPScheck.Value=0;
                app.Gxcheck.Value= 0;
                app.Gycheck.Value= 0;
                app.GcombCheck.Value= 0;
                plot(app.UIAxes,0,0);
                xlabel(app.UIAxes,'distance (m)');
                ylabel(app.UIAxes,'');
            elseif strcmp(value,'Gx / Speed')
                delete(app.appGPS)
                app.GPScheck.Visible='off';
                app.GPSSlider.Visible='off';
                legend(app.UIAxes,'off')
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                plot(app.UIAxes, app.Speed, app.AccelX_GPS, '+');
                xlabel(app.UIAxes,'speed (km/h)');
                ylabel(app.UIAxes,'Longitudinal G');
            elseif strcmp(value,'Gy / Speed')
                delete(app.appGPS)
                app.GPScheck.Visible='off';
                app.GPSSlider.Visible='off';
                legend(app.UIAxes,'off')
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                plot(app.UIAxes, app.Speed, app.AccelY_GPS, '+');
                xlabel(app.UIAxes,'speed (km/h)');
                ylabel(app.UIAxes,'Lateral G');
            elseif strcmp(value, 'Gcomb / Speed')
                delete(app.appGPS)
                app.GPScheck.Visible='off';
                app.GPSSlider.Visible='off';
                legend(app.UIAxes,'off')
                app.Gxcheck.Visible='off';
                app.Gycheck.Visible='off';
                app.GcombCheck.Visible='off';
                plot(app.UIAxes, app.Speed, app.Accelcomb_GPS, '+');
                xlabel(app.UIAxes,'speed (km/h)');
                ylabel(app.UIAxes,'Combined G');
            end
        end

        % Value changed function: Gxcheck
        function GxcheckValueChanged(app, event)
            value = app.Gxcheck.Value;
            if value == 1
                app.L(1) = 1;
            else
                app.L(1) = 0;
            end   
            Majplot(app,app.GPSSlider.Value);
        end

        % Value changed function: Gycheck
        function GycheckValueChanged(app, event)
            value = app.Gycheck.Value;
            if value == 1
               app.L(2) = 1;
            else
               app.L(2) = 0;
            end 
            Majplot(app,app.GPSSlider.Value);
        end

        % Value changed function: GcombCheck
        function GcombCheckValueChanged(app, event)
            value = app.GcombCheck.Value;
            if value == 1
                app.L(3) = 1;
            else
                app.L(3) = 0;
            end 
            Majplot(app,app.GPSSlider.Value);
        end

        % Value changed function: GPScheck
        function GPScheckValueChanged(app, event)
            value = app.GPScheck.Value;
            if value ==1
                app.appGPS=GPS(app.CallingApp);
                app.GPSSlider.Visible='on';
                app.GPSSlider.Value = 0;
                if app.t
                    app.GPSSlider.Limits = [0 app.T50(end)];
                else
                    app.GPSSlider.Limits = [0 app.Distance(end)];
                end
                UpdateGPS(app.appGPS,app.t,app.GPSSlider.Value);
                Majplot(app,app.GPSSlider.Value);
            else
                app.GPSSlider.Visible='off';
                delete(app.appGPS);
                Majplot(app,app.GPSSlider.Value);
            end
        end

        % Value changing function: GPSSlider
        function GPSSliderValueChanging(app, event)
            changingValue = event.Value;
            UpdateGPS(app.appGPS,app.t,changingValue);
            Majplot(app,changingValue);
        end

        % Close request function: figure
        function figureCloseRequest(app, event)
            delete(app.appGPS)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure = uifigure('Visible', 'off');
            app.figure.Name = 'G-force';
            app.figure.CloseRequestFcn = createCallbackFcn(app, @figureCloseRequest, true);
            app.UIAxes = uiaxes(app.figure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotchoiceLabel = uilabel(app.figure);
            app.PlotchoiceLabel.Text = 'Plot choice';
            app.Choixduplot = uidropdown(app.figure);
            app.Choixduplot.Items = {'Traction Circle', 'Accels / Temps', 'Accels / Distance', 'Gx / Speed', 'Gy / Speed', 'Gcomb / Speed'};
            app.Choixduplot.ValueChangedFcn = createCallbackFcn(app, @ChoixduplotValueChanged, true);
            app.Choixduplot.Value = 'Traction Circle';
            app.Gxcheck = uicheckbox(app.figure);
            app.Gxcheck.ValueChangedFcn = createCallbackFcn(app, @GxcheckValueChanged, true);
            app.Gxcheck.Text = 'Gx';
            app.Gycheck = uicheckbox(app.figure);
            app.Gycheck.ValueChangedFcn = createCallbackFcn(app, @GycheckValueChanged, true);
            app.Gycheck.Text = 'Gy';
            app.GcombCheck = uicheckbox(app.figure);
            app.GcombCheck.ValueChangedFcn = createCallbackFcn(app, @GcombCheckValueChanged, true);
            app.GcombCheck.Text = 'Gcomb';
            app.GPScheck = uicheckbox(app.figure);
            app.GPScheck.ValueChangedFcn = createCallbackFcn(app, @GPScheckValueChanged, true);
            app.GPScheck.Text = 'GPS';
            app.GPSSlider = uislider(app.figure);
            app.GPSSlider.ValueChangingFcn = createCallbackFcn(app, @GPSSliderValueChanging, true);
            % Show the figure after all components are created
            app.figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gforce(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure)
        end
    end
end