classdef GripFactor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Table                  matlab.ui.container.Tab
        UITable                matlab.ui.control.Table
        PlotsTab               matlab.ui.container.Tab
        AbcissaButtonGroup     matlab.ui.container.ButtonGroup
        LapnumberButton        matlab.ui.control.RadioButton
        LaptimeButton          matlab.ui.control.RadioButton
        GripfactorButtonGroup  matlab.ui.container.ButtonGroup
        OverallButton          matlab.ui.control.RadioButton
        CorneringButton        matlab.ui.control.RadioButton
        BrakingButton          matlab.ui.control.RadioButton
        TractionButton         matlab.ui.control.RadioButton
        LapPlot                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp
        Accelcomb
        GripO % Grip overall ( accelComb > 1G)
        GripC %Grip Cornering (AccelY > 0.8 G)
        GripB %Grip Branking (accelX <-1G)
        GripT %Grip Traction (accelX > 0.5 G)
    end

    methods (Access = private)
    
        function B = LapSelect(app,Lapnb,A,gps)
            B = [];
            if gps==0
                for k=find(app.CallingApp.Tacc==app.CallingApp.TLap(Lapnb)):find(app.CallingApp.Tacc==app.CallingApp.TLap(Lapnb+1))
                    B = [B A(k)];
                end
            elseif gps==1
                for k=find(app.CallingApp.Tgps==app.CallingApp.TLap(Lapnb)):find(app.CallingApp.Tgps==app.CallingApp.TLap(Lapnb+1))
                    B = [B A(k)];
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            
            %remplissage des variables de cette fenêtre
            for i=1:length(app.CallingApp.AccelX)
                app.Accelcomb(i)=sqrt(app.CallingApp.AccelX(i)^2+app.CallingApp.AccelY(i)^2);
                if app.Accelcomb(i) > 0.8
                    app.GripO(i) = app.Accelcomb(i);
                else
                    app.GripO(i) = NaN;
                end
                if abs(app.CallingApp.AccelY(i)) > 0.5
                    app.GripC(i) = app.Accelcomb(i);
                else
                    app.GripC(i) = NaN;
                end
                if app.CallingApp.AccelX(i) < -1
                    app.GripB(i) = app.Accelcomb(i);
                else
                    app.GripB(i) = NaN;
                end
                if app.CallingApp.AccelX(i) > 0.5
                    app.GripT(i) = app.Accelcomb(i);
                else
                    app.GripT(i) = NaN;
                end 
            end
            
            %Remplissage de la table
            app.UITable.Data(:,1)= 1:1:app.CallingApp.Lap(end);
            app.UITable.Data(:,2) = app.CallingApp.LapTime;
            for k=1:app.CallingApp.Lap(end)
                digits(3);
                app.UITable.Data(k,3) = vpa(nanmean(LapSelect(app,k,app.GripO,0),'native'));
                app.UITable.Data(k,4) = vpa(nanmean(LapSelect(app,k,app.GripC,0),'native'));
                app.UITable.Data(k,5) = vpa(nanmean(LapSelect(app,k,app.GripB,0),'native'));
                app.UITable.Data(k,6) = vpa(nanmean(LapSelect(app,k,app.GripT,0),'native'));
            end
            
            %tracage du plot
            plot(app.LapPlot,app.UITable.Data(:,1),app.UITable.Data(k,3),'--o')
            xlabel(app.LapPlot,'Lap number');
            ylabel(app.LapPlot,'Grip Overall');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.UITable = uitable(app.Table);
            app.UITable.ColumnName = {'Lap Number'; 'Lap times (s)'; 'Grip factor overall'; 'Grip factor cornering'; 'Grip factor braking'; 'Grip factor traction'};
            app.UITable.RowName = {};
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.AbcissaButtonGroup = uibuttongroup(app.PlotsTab);
            app.AbcissaButtonGroup.Title = 'Abcissa';
            app.LapnumberButton = uiradiobutton(app.AbcissaButtonGroup);
            app.LapnumberButton.Text = 'Lap number';
            app.LapnumberButton.Value = true;
            app.LaptimeButton = uiradiobutton(app.AbcissaButtonGroup);
            app.LaptimeButton.Text = 'Lap time';
            app.GripfactorButtonGroup = uibuttongroup(app.PlotsTab);
            app.GripfactorButtonGroup.Title = 'Grip factor';
            app.OverallButton = uiradiobutton(app.GripfactorButtonGroup);
            app.OverallButton.Text = 'Overall';
            app.OverallButton.Value = true;
            app.CorneringButton = uiradiobutton(app.GripfactorButtonGroup);
            app.CorneringButton.Text = 'Cornering';
            app.BrakingButton = uiradiobutton(app.GripfactorButtonGroup);
            app.BrakingButton.Text = 'Braking';
            app.TractionButton = uiradiobutton(app.GripfactorButtonGroup);
            app.TractionButton.Text = 'Traction';
            app.LapPlot = uiaxes(app.PlotsTab);
            title(app.LapPlot, 'Title')
            xlabel(app.LapPlot, 'X')
            ylabel(app.LapPlot, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = GripFactor(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end