classdef Roll_Gradient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        RollGradientTab      matlab.ui.container.Tab
        RG_3                 matlab.ui.control.Label
        Offset               matlab.ui.control.Label
        RGradient            matlab.ui.control.Label
        RollGradientGLabel   matlab.ui.control.Label
        RollDropDown         matlab.ui.control.DropDown
        RollDropDownLabel    matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
        FrontrearRatioTab    matlab.ui.container.Tab
        meanratio            matlab.ui.control.Label
        AverageratioLabel    matlab.ui.control.Label
        UIAxes2              matlab.ui.control.UIAxes
        PitchGradientTab     matlab.ui.container.Tab
        PGradient            matlab.ui.control.Label
        PitchGradientGLabel  matlab.ui.control.Label
        UIAxes_2             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % datavisu app
        Roll % Roll of the car (°)
        RG % Roll gradient (°/G)
        FRoll % Front roll of the car (°)
        FRG % front roll gradient (°/G)
        RRoll % Rear roll of the car (°)
        RRG % rear roll ratio (°/G)
        ratio % distribution de raideur avant arrière
        Mratio % moyenne du ratio
        AccelY %Accélération latéral (G)
        AccelX %Accélération longitudinale (G)
        Time %Liste des temps (s)
        Pitch %pitch of the car (°)
        PG %Pitch gradient (°/G)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
        app.UIFigure.Name = 'Roll gradient';
        app.CallingApp = Mainapp;   
        
        %% paramétrage Invictus
        MRf = 1.167; % front motion ratio, dampers to wheels
        MRr = 1.28; % rear motion ratio
        Tf = 1254; % front track drive en mm
        Tr = 1200; % rear track drive en mm
        WheelBase = 1.575*1000;% Wheelbase in mmm
        % Récupération des données
        XFR = get_data(app.CallingApp.data_struct,'Height_RF'); 
        XFL = get_data(app.CallingApp.data_struct,'Height_LF'); 
        XRR = get_data(app.CallingApp.data_struct,'Height_RR'); 
        id_XRL = get_data_index(app.CallingApp.data_struct,'Height_LR'); 
        XRL = app.CallingApp.data_struct(id_XRL).Data;
        
        
        AccelYindex = get_data_index(app.CallingApp.data_struct, 'AccelY');
        index_height = get_data_index(app.CallingApp.data_struct, "Height_RR");
        P2 = strcat('F',num2str(app.CallingApp.data_struct(index_height).Frequency));
        app.Time = get_data(app.CallingApp.data_struct, P2);
        
        if app.CallingApp.data_struct(AccelYindex).Frequency == app.CallingApp.data_struct(index_height).Frequency
            app.AccelY = app.CallingApp.data_struct(AccelYindex).Data;
            app.AccelX = get_data(app.CallingApp.data_struct,'AccelX');
        else
            P1 = strcat('F',num2str(app.CallingApp.data_struct(AccelYindex).Frequency)); %le nom des temps pour les bonnes fréquences 
            idP1 = strcmp({app.CallingApp.data_struct.Name}, P1)==1; %les listes des temps pour les bonnes fréquences 
            idP2 = strcmp({app.CallingApp.data_struct.Name}, P2)==1;
            
            app.AccelY = interp1(app.CallingApp.data_struct(idP1).Data,app.CallingApp.data_struct(AccelYindex).Data,app.CallingApp.data_struct(idP2).Data); %Interpolation
            app.AccelX = interp1(app.CallingApp.data_struct(idP1).Data,get_data(app.CallingApp.data_struct,'AccelX'),app.CallingApp.data_struct(idP2).Data); %Interpolation
        end   

        
        %% Calcul des roll et du ratio 
        app.Roll = rad2deg(atan(((XFL-XFR)*MRf+(XRL-XRR)*MRr)/(Tr+Tf)));
        app.FRoll = rad2deg(atan((XFL-XFR)*MRf/Tf));
        app.RRoll = rad2deg(atan((XRL-XRR)*MRr/Tr));
        for i=1:length(app.RRoll)
            if app.RRoll(i)==0
                app.ratio(i) = 1;
            else
                app.ratio(i) = abs(app.FRoll(i)/app.RRoll(i));
            end
        end
        
        app.Pitch = rad2deg(atan(((XFL+XFR-mean(XFL+XFR))*MRf-(XRL+XRR-mean(XRL+XRR))*MRr)/(2*WheelBase)));
        
        %Tracé des courbes
        %roll gradient
        plot(app.UIAxes,app.AccelY,app.Roll,'*')
        xlabel(app.UIAxes,'Lateral acceleration (G)')
        ylabel(app.UIAxes, 'Roll (°)')
        hold(app.UIAxes,'on')
        p=polyfit(app.AccelY,app.Roll,1);
        app.RG=p(1);
        app.RGradient.Text = num2str(round(app.RG,4),2);
        app.Offset.Text = num2str(p(2),2);
        plot(app.UIAxes,app.AccelY,polyval(p,app.AccelY))
        hold(app.UIAxes,'off')
        
        %ratio
        plot(app.UIAxes2,app.Time,app.ratio)
        xlabel(app.UIAxes2,'Time (s)')
        ylabel(app.UIAxes2,'Roll ratio')
        app.meanratio.Text = num2str(round(mean(app.ratio,'native'),2));
        assignin('base','ratio',app.ratio)
        assignin('base','Roll_susp',app.Roll)
        assignin('base','Accely_GPS',app.AccelY)
        
        %Pitch
        plot(app.UIAxes_2,app.AccelX,app.Pitch,'*')
        xlabel(app.UIAxes_2,'Longitudinal acceleration (G)')
        ylabel(app.UIAxes_2, 'Pitch (°)')
        hold(app.UIAxes_2,'on')
        p=polyfit(app.AccelX,app.Pitch,1);
        app.PG=p(1);
        app.PGradient.Text = num2str(round(app.PG,4),2);
        plot(app.UIAxes_2,app.AccelX,polyval(p,app.AccelX))
        hold(app.UIAxes_2,'off')
        end

        % Value changed function: RollDropDown
        function RollDropDownValueChanged(app, event)
            value = app.RollDropDown.Value;
            if strcmp(value,'All')
                plot(app.UIAxes,app.AccelY,app.Roll,'*')
                xlabel(app.UIAxes,'Lateral acceleration (G)')
                ylabel(app.UIAxes, 'Roll (°)')
                hold(app.UIAxes,'on')
                p=polyfit(app.AccelY,app.Roll,1);
                app.RG=p(1);
                app.RGradient.Text = num2str(round(app.RG,4),2);
                app.Offset.Text = num2str(p(2),2);
                plot(app.UIAxes,app.AccelY,polyval(p,app.AccelY))
                hold(app.UIAxes,'off')  
            elseif strcmp(value,'Front')
                plot(app.UIAxes,app.AccelY,app.FRoll,'*')
                xlabel(app.UIAxes,'Lateral acceleration (G)')
                ylabel(app.UIAxes, 'Front Roll (°)')
                hold(app.UIAxes,'on')
                p=polyfit(app.AccelY,app.FRoll,1);
                app.FRG=p(1);
                app.RGradient.Text = num2str(round(app.FRG,4),2);
                app.Offset.Text = num2str(p(2),2);
                plot(app.UIAxes,app.AccelY,polyval(p,app.AccelY))
                hold(app.UIAxes,'off')
            else
                plot(app.UIAxes,app.AccelY,app.RRoll,'*')
                xlabel(app.UIAxes,'Lateral acceleration (G)')
                ylabel(app.UIAxes, 'Rear Roll (°)')
                hold(app.UIAxes,'on')
                p=polyfit(app.AccelY,app.RRoll,1);
                app.RRG=p(1);
                app.RGradient.Text = num2str(round(app.RRG,4),2);
                app.Offset.Text = num2str(p(2),2);
                plot(app.UIAxes,app.AccelY,polyval(p,app.AccelY))
                hold(app.UIAxes,'off')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RollGradientTab = uitab(app.TabGroup);
            app.RollGradientTab.Title = 'Roll Gradient';
            app.UIAxes = uiaxes(app.RollGradientTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RollDropDownLabel = uilabel(app.RollGradientTab);
            app.RollDropDownLabel.Text = 'Roll';
            app.RollDropDown = uidropdown(app.RollGradientTab);
            app.RollDropDown.Items = {'All', 'Front', 'Rear', ''};
            app.RollDropDown.ValueChangedFcn = createCallbackFcn(app, @RollDropDownValueChanged, true);
            app.RollDropDown.Value = 'All';
            app.RollGradientGLabel = uilabel(app.RollGradientTab);
            app.RollGradientGLabel.Text = 'Roll Gradient (°/G) :';
            app.RGradient = uilabel(app.RollGradientTab);
            app.Offset = uilabel(app.RollGradientTab);
            app.RG_3 = uilabel(app.RollGradientTab);
            app.RG_3.Text = 'Offset :';
            app.FrontrearRatioTab = uitab(app.TabGroup);
            app.FrontrearRatioTab.Title = 'Front/rear Ratio';
            app.UIAxes2 = uiaxes(app.FrontrearRatioTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.AverageratioLabel = uilabel(app.FrontrearRatioTab);
            app.AverageratioLabel.Text = 'Average ratio :';
            app.meanratio = uilabel(app.FrontrearRatioTab);
            app.PitchGradientTab = uitab(app.TabGroup);
            app.PitchGradientTab.Title = 'Pitch Gradient';
            app.UIAxes_2 = uiaxes(app.PitchGradientTab);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.PitchGradientGLabel = uilabel(app.PitchGradientTab);
            app.PitchGradientGLabel.Text = 'Pitch Gradient (°/G) :';
            app.PGradient = uilabel(app.PitchGradientTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Roll_Gradient(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end