classdef Temps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
    end


    properties (Access = private)
        Callingapp % Description
    end

    methods (Access = private)
    
        function DataLap = LapSelect(app,Lapnb,Dataname)
            F10 = get_data(app.Callingapp.data_struct,"F10");%vecteur des temps à 10Hz
            LapCount = get_data(app.Callingapp.data_struct,"LapCount"); %vecteur du numéro de tour à 10Hz
            
            if sum(LapCount==Lapnb)>1
                startTime = min(F10(LapCount==Lapnb)); %Temps de début du tour
                endTime = max(F10(LapCount==Lapnb)); %Temps de fin du tour
            
                index_data = get_data_index(app.Callingapp.data_struct,Dataname); 
                P = strcat('F',num2str(app.Callingapp.data_struct(index_data).Frequency));
                TimeData = get_data(app.Callingapp.data_struct, P); %vecteur des temps associé aux data
                
                Databrut = get_data(app.Callingapp.data_struct,Dataname); %vecteur des data
                DataLap = Databrut(TimeData>startTime & TimeData<endTime);
            else
                DataLap = [];
            end
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.Callingapp = Mainapp;
            Tlap = get_data(app.Callingapp.data_struct,"TLap");
            app.UITable.Data(:,1)= 1:length(Tlap);
            app.UITable.Data(:,2) = round(Tlap,2);

            for k=1:length(Tlap)
                digit=3;
                app.UITable.Data(k,3) = mean(LapSelect(app,k,"Speed"),'native');
                app.UITable.Data(k,4) = mean(LapSelect(app,k,"RPM"),'native');
                app.UITable.Data(k,5) = mean(LapSelect(app,k,"TPS"),'native');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Lap_number'; 'Time'; 'Average_speed'; 'Average_RPM'; 'Average_TPS'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Temps(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end