classdef Track_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Trackplot          matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        RightPanel         matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        DataDropDownLabel  matlab.ui.control.Label
        DataDropDown       matlab.ui.control.DropDown
        Label              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp % Datavisu
    end
    
    methods (Access = private)
        
         function [] = track_plot(app, X, Y, L, T,Label)
            % X et Y sont des coordonnées venant de lat_longi2X_Y
            % speed est en km/h
            cla(app.UIAxes,'reset') % reset le graph
            z = zeros(size(X'));
            col = interp1(T,L,app.CallingApp.Tgps)';  % This is the color, vary with x in this case.
            surface(app.UIAxes, [X';X'],[Y';Y'],[z;z],[col;col],'facecol','no','edgecol','interp','linew',2);
            c = colorbar(app.UIAxes);
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            c.Label.String = Label;
            app.Label.Text = Label;
            axis(app.UIAxes,[min(X)-10,max(X)+10,min(Y)-10,max(Y)+10]);
            set(app.UIAxes,'Visible','off')
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Speed,app.CallingApp.Tgps,'Speed (km/h)')
            app.DataDropDown.Items = {'AccelX'
            'AccelY'
            'AccelZ'
            'Yaw'
            'Pitch'
            'Roll'
            'Speed'
            'Steering'
            'XFL'
            'XFR'
            'XRL'
            'XRR'
            'WFL'
            'WFR'    
            'WRL'
            'WRR'
            'BPF'
            'BPR'
            'RPM'
            'Gear'
            'Pair'
            'Tair'
            'Twater'
            'Poil'
            'Pfuel'
            'Lambda'
            'TPS'
            'Vbat'
            'Advance'
            'Injection'
            'FuelCons'};
        
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            if strcmp(value,'AccelX')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.AccelX,app.CallingApp.Tacc,'Longitudinal acceleration (G)')
            elseif strcmp(value,'AccelY')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.AccelY,app.CallingApp.Tacc,'Lateral latérale (G)')
            elseif strcmp(value,'AccelZ')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.AccelZ,app.CallingApp.Tacc,'Vertival acceleration (G)')
            elseif strcmp(value,'Yaw')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Yaw,app.CallingApp.Tacc,'Yaw (°/s)')
            elseif strcmp(value,'Pitch')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Pitch,app.CallingApp.Tacc,'Pitch (°/s)')
            elseif strcmp(value,'Roll')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Roll,app.CallingApp.Tacc,'Roll (°/s)')
            elseif strcmp(value,'Speed')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Speed,app.CallingApp.Tgps,'Speed (km/h)')
            elseif strcmp(value,'Steering')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Steering,app.CallingApp.Tacc,'Steering angle (°)')
            elseif strcmp(value,'WFL')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.WFL,app.CallingApp.Tacc,'Front left wheel speed (km/h)')
            elseif strcmp(value,'WFR')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.WFR,app.CallingApp.Tacc,'Front right wheel speed (km/h)')
            elseif strcmp(value,'WRL')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.WRL,app.CallingApp.Tacc,'Rear left wheel speed (km/h)')
            elseif strcmp(value,'WRR')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.WRR,app.CallingApp.Tacc,'Rear right wheel speed (km/h)')
            elseif strcmp(value,'BPF')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.BPF,app.CallingApp.Tacc,'Front brake pressure (bar)')
            elseif strcmp(value,'BPR')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.BPR,app.CallingApp.Tacc,'Rear brake pressure (bar)')
            elseif strcmp(value,'RPM')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.RPM,app.CallingApp.Tacc,'Motor speed (rpm)')
            elseif strcmp(value,'Gear')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Gear,app.CallingApp.Tacc,'Engaged gear')
            elseif strcmp(value,'Pair')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Pair,app.CallingApp.Tacc,'Air pressure (bar)')
            elseif strcmp(value,'Tair')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Tair,app.CallingApp.Tacc,'Air temperature (°)')
            elseif strcmp(value,'Twater')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Twater,app.CallingApp.Tacc,'Water temperature (°)')
            elseif strcmp(value,'Poil')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Poil,app.CallingApp.Tacc,'Oil pressure (bar)')
            elseif strcmp(value,'Pfuel')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Pfuel,app.CallingApp.Tacc,'Puel pressure (bar)')
            elseif strcmp(value,'Lambda')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Lambda,app.CallingApp.Tacc,'Lambda')
            elseif strcmp(value,'TPS')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.TPS,app.CallingApp.Tacc,'Throttle (%)')
            elseif strcmp(value,'Vbat')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Vbat,app.CallingApp.Tacc,'Battery voltage (V)')
            elseif strcmp(value,'Advance')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Advance,app.CallingApp.Tacc,'Spark advance (°)')
            elseif strcmp(value,'Injection')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.Injection,app.CallingApp.Tacc,'Injection time (ms)')
            elseif strcmp(value,'FuelCons')
                track_plot(app, app.CallingApp.X, app.CallingApp.Y, app.CallingApp.FuelCons,app.CallingApp.Tacc,'Fuel consumption (L/100km)')
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Trackplot.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Trackplot = uifigure('Visible', 'off');
            app.Trackplot.Name = 'UI Figure';
            app.Trackplot.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Trackplot);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DataDropDownLabel = uilabel(app.RightPanel);
            app.DataDropDownLabel.Text = 'Data :';
            app.DataDropDown = uidropdown(app.RightPanel);
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.Label = uilabel(app.RightPanel);
            % Show the figure after all components are created
            app.Trackplot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Track_plot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Trackplot)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Trackplot)
        end
    end
end