classdef Vital < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FuelPressurebarPanel     matlab.ui.container.Panel
        FuelsurgeButton          matlab.ui.control.Button
        Pfuelmoy                 matlab.ui.control.Label
        Label9_2                 matlab.ui.control.Label
        PfuelMin                 matlab.ui.control.Label
        PressureminLabel         matlab.ui.control.Label
        SignalsPanel             matlab.ui.container.Panel
        FuelPressureButton       matlab.ui.control.CheckBox
        BatteryVoltageButton     matlab.ui.control.CheckBox
        AirPressureButton        matlab.ui.control.CheckBox
        AirTempButton            matlab.ui.control.CheckBox
        OilPressureButton        matlab.ui.control.CheckBox
        WaterTempButton          matlab.ui.control.CheckBox
        RPMButton                matlab.ui.control.CheckBox
        PlotButton               matlab.ui.control.Button
        OilPressureKPaPanel      matlab.ui.container.Panel
        Poilmoy                  matlab.ui.control.Label
        Label9                   matlab.ui.control.Label
        PoilMax                  matlab.ui.control.Label
        PressuremaxLabel_2       matlab.ui.control.Label
        AirinfoPressureinbartempincPanel  matlab.ui.container.Panel
        TairMoy                  matlab.ui.control.Label
        PairMax                  matlab.ui.control.Label
        AveragetemperaureLabel   matlab.ui.control.Label
        PressureminLabel_2       matlab.ui.control.Label
        WaterTemperaturecPanel   matlab.ui.container.Panel
        TwaterMoy                matlab.ui.control.Label
        TwaterMax                matlab.ui.control.Label
        AverageLabel_2           matlab.ui.control.Label
        MaxLabel_2               matlab.ui.control.Label
        BatteryVoltageVoltPanel  matlab.ui.container.Panel
        Vmin                     matlab.ui.control.Label
        Vmoy                     matlab.ui.control.Label
        Vmax                     matlab.ui.control.Label
        MaxLabel                 matlab.ui.control.Label
        AverageLabel             matlab.ui.control.Label
        MinLabel                 matlab.ui.control.Label
    end


    properties (Access = private)
        Callingapp % Description
        WaterTemp;
        Battery;
        AirTemp;
        Pair;
        FuelPress;
        OilPress; 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.UIFigure.Name = 'Vitals datas';
            app.Callingapp = Mainapp;
            %app.Battery
            app.Battery = get_data(app.Callingapp.data_struct, "Batterie");
            app.Vmax.Text = num2str(round(max(app.Battery),1));
            app.Vmoy.Text = num2str(round(mean(app.Battery,'native'),1));
            app.Vmin.Text = num2str(round(min(app.Battery),1));
            
            %water
            app.WaterTemp = get_data(app.Callingapp.data_struct, "WaterTemp");
            app.TwaterMax.Text = num2str(round(max(app.WaterTemp),1));
            RPM=get_data(app.Callingapp.data_struct, "RPM");
            for k=1:length(app.WaterTemp)
                if RPM(k)>100
                    L(k) = app.WaterTemp(k);
                end
            end
            
            app.TwaterMoy.Text = int2str(round(mean(L,'native'),1));
            
            %Air
            app.Pair=get_data(app.Callingapp.data_struct, "MAP");
            app.AirTemp = get_data(app.Callingapp.data_struct, "AirTemp");
            app.PairMax.Text = num2str(round(min(app.Pair),2));
            app.TairMoy.Text = num2str(round(mean(app.AirTemp,'native'),1));
            
            %Oil
            L = [];
            app.OilPress = get_data(app.Callingapp.data_struct, "OilPress");
            app.PoilMax.Text = num2str(max(app.OilPress));
            for k=1:length(app.OilPress)
                
                if RPM(k)>100
                    L(k) = app.OilPress(k);
                end
            end
            app.Poilmoy.Text = num2str(round(mean(L,'native'),1));
            
            %Fuel
            app.FuelPress=get_data(app.Callingapp.data_struct, "FuelPressur");
            app.PfuelMin.Text = num2str(round(min(app.FuelPress),1)); 
            app.Pfuelmoy.Text = num2str(round(mean(app.FuelPress,'native'),1)); 
            
            %check box
            app.RPMButton.Value = 1;
            app.WaterTempButton.Value = 1;
            app.OilPressureButton.Value = 1;
            app.AirTempButton.Value = 1;
            app.AirPressureButton.Value = 1;
            app.BatteryVoltageButton.Value = 1;
            app.FuelPressureButton.Value = 1;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            n = app.RPMButton.Value + app.WaterTempButton.Value + app.OilPressureButton.Value + app.AirTempButton.Value + app.AirPressureButton.Value + app.BatteryVoltageButton.Value+app.FuelPressureButton.Value;
            A=[0 0 0 0 0 0];
            T50=freq2time(app.Callingapp.data_struct,50);
            if app.RPMButton.Value
                rpm_sp = subplot(n,6,[ 1 2 3 4 5 6]);
                RPM=get_data(app.Callingapp.data_struct, "RPM");
                plot(rpm_sp,T50,RPM);
                title(rpm_sp,'RPM (tr/min)');
                grid(rpm_sp,'on');
                A(1)=1;
            end
            if app.WaterTempButton.Value
                Twater = subplot(n,6,[ sum(A)*6+1  sum(A)*6+2 sum(A)*6+3 sum(A)*6+4 sum(A)*6+5 sum(A)*6+6]);
                plot(Twater,T50,app.WaterTemp);
                title(Twater,'Water Temperrature (°c)');
                grid(Twater,'on');
                A(2) = 1;
            end
            if app.OilPressureButton.Value
                poil=subplot(n,6,[ sum(A)*6+1  sum(A)*6+2 sum(A)*6+3 sum(A)*6+4 sum(A)*6+5 sum(A)*6+6]);
                plot(poil,T50,app.AirTemp);
                title(poil,'Oil Pressure (bar)');
                grid(poil,'on');
                A(3)=1;
            end
            if app.AirTempButton.Value
                Tair=subplot(n,6,[ sum(A)*6+1  sum(A)*6+2 sum(A)*6+3 sum(A)*6+4 sum(A)*6+5 sum(A)*6+6]);
                plot(Tair,T50,app.AirTemp);
                title(Tair,'Air Temperature (°c)');
                grid(Tair,'on');
                A(4)=1;
            end
            if app.AirPressureButton.Value
                Pair_sp=subplot(n,6,[ sum(A)*6+1  sum(A)*6+2 sum(A)*6+3 sum(A)*6+4 sum(A)*6+5 sum(A)*6+6]);
                plot(Pair_sp,T50,app.Pair);
                title(Pair_sp,'Air Pressure (bar)');
                grid(Pair_sp,'on');
                A(5)=1;
            end
            if app.BatteryVoltageButton.Value
                BV=subplot(n,6,[ sum(A)*6+1  sum(A)*6+2 sum(A)*6+3 sum(A)*6+4 sum(A)*6+5 sum(A)*6+6]);
                plot(BV,T50,app.Battery);
                title(BV,'Battery Voltage (V)');
                grid(BV,'on');
                A(6)=1;
            end
            if app.FuelPressureButton.Value
                FP=subplot(n,6,[ sum(A)*6+1  sum(A)*6+2 sum(A)*6+3 sum(A)*6+4 sum(A)*6+5 sum(A)*6+6]);
                plot(FP,T50,app.FuelPress);
                title(FP,'Fuel Pressure (bar)');
                grid(BV,'on')
            end
        end

        % Button pushed function: FuelsurgeButton
        function FuelsurgeButtonPushed(app, event)
            plot(get_data(app.Callingapp.data_struct, "AccelY"),app.FuelPress,'*','DisplayName','Vérification du déjaugeage');
            xlabel('Latéral acceleration (G)');
            ylabel('Fuel Pressure (bar)');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BatteryVoltageVoltPanel = uipanel(app.UIFigure);
            app.BatteryVoltageVoltPanel.Title = 'Battery Voltage (Volt)';
            app.MinLabel = uilabel(app.BatteryVoltageVoltPanel);
            app.MinLabel.Text = 'Min :';
            app.AverageLabel = uilabel(app.BatteryVoltageVoltPanel);
            app.AverageLabel.Text = 'Average :';
            app.MaxLabel = uilabel(app.BatteryVoltageVoltPanel);
            app.MaxLabel.Text = 'Max :';
            app.Vmax = uilabel(app.BatteryVoltageVoltPanel);
            app.Vmoy = uilabel(app.BatteryVoltageVoltPanel);
            app.Vmin = uilabel(app.BatteryVoltageVoltPanel);
            app.WaterTemperaturecPanel = uipanel(app.UIFigure);
            app.WaterTemperaturecPanel.Title = 'Water Temperature (°c)';
            app.MaxLabel_2 = uilabel(app.WaterTemperaturecPanel);
            app.MaxLabel_2.Text = 'Max :';
            app.AverageLabel_2 = uilabel(app.WaterTemperaturecPanel);
            app.AverageLabel_2.Text = 'Average :';
            app.TwaterMax = uilabel(app.WaterTemperaturecPanel);
            app.TwaterMoy = uilabel(app.WaterTemperaturecPanel);
            app.AirinfoPressureinbartempincPanel = uipanel(app.UIFigure);
            app.AirinfoPressureinbartempincPanel.Title = 'Air info (Pressure in bar, temp in °c)';
            app.PressureminLabel_2 = uilabel(app.AirinfoPressureinbartempincPanel);
            app.PressureminLabel_2.Text = 'Pressure min :';
            app.AveragetemperaureLabel = uilabel(app.AirinfoPressureinbartempincPanel);
            app.AveragetemperaureLabel.Text = 'Average temperaure :';
            app.PairMax = uilabel(app.AirinfoPressureinbartempincPanel);
            app.TairMoy = uilabel(app.AirinfoPressureinbartempincPanel);
            app.OilPressureKPaPanel = uipanel(app.UIFigure);
            app.OilPressureKPaPanel.Title = 'Oil Pressure (KPa)';
            app.PressuremaxLabel_2 = uilabel(app.OilPressureKPaPanel);
            app.PressuremaxLabel_2.Text = 'Pressure max :';
            app.PoilMax = uilabel(app.OilPressureKPaPanel);
            app.Label9 = uilabel(app.OilPressureKPaPanel);
            app.Label9.Text = {'Average Pressure while'; 'motor running:'};
            app.Poilmoy = uilabel(app.OilPressureKPaPanel);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalsPanel = uipanel(app.UIFigure);
            app.SignalsPanel.Title = 'Signals';
            app.RPMButton = uicheckbox(app.SignalsPanel);
            app.RPMButton.Text = 'RPM';
            app.WaterTempButton = uicheckbox(app.SignalsPanel);
            app.WaterTempButton.Text = 'Water Temp';
            app.OilPressureButton = uicheckbox(app.SignalsPanel);
            app.OilPressureButton.Text = 'Oil Pressure';
            app.AirTempButton = uicheckbox(app.SignalsPanel);
            app.AirTempButton.Text = 'Air Temp';
            app.AirPressureButton = uicheckbox(app.SignalsPanel);
            app.AirPressureButton.Text = 'Air Pressure';
            app.BatteryVoltageButton = uicheckbox(app.SignalsPanel);
            app.BatteryVoltageButton.Text = 'Battery voltage';
            app.FuelPressureButton = uicheckbox(app.SignalsPanel);
            app.FuelPressureButton.Text = 'Fuel Pressure';
            app.FuelPressurebarPanel = uipanel(app.UIFigure);
            app.FuelPressurebarPanel.Title = 'Fuel Pressure (bar)';
            app.PressureminLabel = uilabel(app.FuelPressurebarPanel);
            app.PressureminLabel.Text = 'Pressure min :';
            app.PfuelMin = uilabel(app.FuelPressurebarPanel);
            app.Label9_2 = uilabel(app.FuelPressurebarPanel);
            app.Label9_2.Text = {'Average Pressure :'; ''};
            app.Pfuelmoy = uilabel(app.FuelPressurebarPanel);
            app.FuelsurgeButton = uibutton(app.FuelPressurebarPanel, 'push');
            app.FuelsurgeButton.ButtonPushedFcn = createCallbackFcn(app, @FuelsurgeButtonPushed, true);
            app.FuelsurgeButton.Text = 'Fuel surge';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vital(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end