classdef XY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Trackplot       matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        RightPanel      matlab.ui.container.Panel
        UIAxes          matlab.ui.control.UIAxes
        YDropDownLabel  matlab.ui.control.Label
        YDropDown       matlab.ui.control.DropDown
        XYLabel         matlab.ui.control.Label
        XDropDownLabel  matlab.ui.control.Label
        XDropDown       matlab.ui.control.DropDown
        PlotButton      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp % Datavisu
        Ydata %Données à afficher en Y
        Xdata %Données à afficher en X
        YLabel %Nom des données en Y
        XLabel %Nom des données en X
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            dataList = {'AccelX'
            'AccelY'
            'AccelZ'
            'Yaw'
            'Pitch'
            'Roll'
            'Speed'
            'Steering'
            'XFL'
            'XFR'
            'XRL'
            'XRR'
            'WFL'
            'WFR'    
            'WRL'
            'WRR'
            'BPF'
            'BPR'
            'RPM'
            'Gear'
            'Pair'
            'Tair'
            'Twater'
            'Poil'
            'Pfuel'
            'Lambda'
            'TPS'
            'Vbat'
            'Advance'
            'Injection'
            'FuelCons'}; %Liste de toutes les données enregistrées par le boîtier RaceCapture
            app.YDropDown.Items = dataList;
            app.XDropDown.Items = dataList;
        end

        % Value changed function: YDropDown
        function YDropDownValueChanged(app, event)
            value = app.YDropDown.Value;
            if strcmp(value,'AccelX')
                app.Ydata = app.CallingApp.AccelX;app.Ylabel = 'Longitudinal acceleration (G)';
            elseif strcmp(value,'AccelY')
                app.Ydata = app.CallingApp.AccelY;
                app.Ylabel = 'Lateral latérale (G)';
            elseif strcmp(value,'AccelZ')
                app.Ydata = app.CallingApp.AccelZ;
                app.Ylabel = 'Vertival acceleration (G)';
            elseif strcmp(value,'Yaw')
                app.Ydata = app.CallingApp.Yaw;
                app.Ylabel = 'Yaw (°/s)';
            elseif strcmp(value,'Pitch')
                app.Ydata = app.CallingApp.Pitch;
                app.Ylabel = 'Pitch (°/s)';
            elseif strcmp(value,'Roll')
                app.Ydata = app.CallingApp.Roll;
                app.Ylabel = 'Roll (°/s)';
            elseif strcmp(value,'Speed')
                app.Ydata = app.CallingApp.Speed;
                app.Ylabel = 'Speed (km/h)';
            elseif strcmp(value,'Steering')
                app.Ydata = app.CallingApp.Steering;
                app.Ylabel = 'Steering angle (°)';
            elseif strcmp(value,'WFL')
                app.Ydata = app.CallingApp.WFL;
                app.Ylabel = 'Front left wheel speed (km/h)';
            elseif strcmp(value,'WFR')
                app.Ydata = app.CallingApp.WFR;
                app.Ylabel = 'Front right wheel speed (km/h)';
            elseif strcmp(value,'WRL')
                app.Ydata = app.CallingApp.WRL;
                app.Ylabel = 'Rear left wheel speed (km/h)';
            elseif strcmp(value,'WRR')
                app.Ydata = app.CallingApp.WRR;
                app.Ylabel = 'Rear right wheel speed (km/h)';
            elseif strcmp(value,'BPF')
                app.Ydata = app.CallingApp.BPF;
                app.Ylabel = 'Front brake pressure (bar)';
            elseif strcmp(value,'BPR')
                app.Ydata = app.CallingApp.BPR;
                app.Ylabel = 'Rear brake pressure (bar)';
            elseif strcmp(value,'RPM')
                app.Ydata = app.CallingApp.RPM;
                app.Ylabel = 'Motor speed (rpm)';
            elseif strcmp(value,'Gear')
                app.Ydata = app.CallingApp.Gear;
                app.Ylabel = 'Engaged gear';
            elseif strcmp(value,'Pair')
                app.Ydata = app.CallingApp.Pair;
                app.Ylabel = 'Air pressure (bar)';
            elseif strcmp(value,'Tair')
                app.Ydata = app.CallingApp.Tair;
                app.Ylabel = 'Air temperature (°)';
            elseif strcmp(value,'Twater')
                app.Ydata = app.CallingApp.Twater;
                app.Ylabel = 'Water temperature (°)';
            elseif strcmp(value,'Poil')
                app.Ydata = app.CallingApp.Poil;
                app.Ylabel = 'Oil pressure (bar)';
            elseif strcmp(value,'Pfuel')
                app.Ydata = app.CallingApp.Pfuel;
                app.Ylabel = 'Puel pressure (bar)';
            elseif strcmp(value,'Lambda')
                app.Ydata = app.CallingApp.Lambda;
                app.Ylabel = 'Lambda';
            elseif strcmp(value,'TPS')
                app.Ydata = app.CallingApp.TPS;
                app.Ylabel = 'Throttle (%)';
            elseif strcmp(value,'Vbat')
                app.Ydata = app.CallingApp.Vbat;
                app.Ylabel = 'Battery voltage (V)';
            elseif strcmp(value,'Advance')
                app.Ydata = app.CallingApp.Advance;
                app.Ylabel = 'Spark advance (°)';
            elseif strcmp(value,'Injection')
                app.Ydata = app.CallingApp.Injection;
                app.Ylabel = 'Injection time (ms)';
            elseif strcmp(value,'FuelCons')
                app.Ydata = app.CallingApp.FuelCons;
                app.Ylabel = 'Fuel consumption (L/100km)';
            end
        end

        % Value changed function: XDropDown
        function XDropDownValueChanged(app, event)
            value = app.XDropDown.Value;
            if strcmp(value,'AccelX')
                app.Xdata = app.CallingApp.AccelX;
                app.Xlabel = 'Longitudinal acceleration (G)';
            elseif strcmp(value,'AccelY')
                app.Xdata = app.CallingApp.AccelY;
                app.Xlabel = 'Lateral latérale (G)';
            elseif strcmp(value,'AccelZ')
                app.Xdata = app.CallingApp.AccelZ;
                app.Xlabel = 'Vertival acceleration (G)';
            elseif strcmp(value,'Yaw')
                app.Xdata = app.CallingApp.Yaw;
                app.Xlabel = 'Yaw (°/s)';
            elseif strcmp(value,'Pitch')
                app.Xdata = app.CallingApp.Pitch;
                app.Xlabel = 'Pitch (°/s)';
            elseif strcmp(value,'Roll')
                app.Xdata = app.CallingApp.Roll;
                app.Xlabel = 'Roll (°/s)';
            elseif strcmp(value,'Speed')
                app.Xdata = app.CallingApp.Speed;
                app.Xlabel = 'Speed (km/h)';
            elseif strcmp(value,'Steering')
                app.Xdata = app.CallingApp.Steering;
                app.Xlabel = 'Steering angle (°)';
            elseif strcmp(value,'WFL')
                app.Xdata = app.CallingApp.WFL;
                app.Xlabel = 'Front left wheel speed (km/h)';
            elseif strcmp(value,'WFR')
                app.Xdata = app.CallingApp.WFR;
                app.Xlabel = 'Front right wheel speed (km/h)';
            elseif strcmp(value,'WRL')
                app.Xdata = app.CallingApp.WRL;
                app.Xlabel = 'Rear left wheel speed (km/h)';
            elseif strcmp(value,'WRR')
                app.Xdata = app.CallingApp.WRR;
                app.Xlabel = 'Rear right wheel speed (km/h)';
            elseif strcmp(value,'BPF')
                app.Xdata = app.CallingApp.BPF;
                app.Xlabel = 'Front brake pressure (bar)';
            elseif strcmp(value,'BPR')
                app.Xdata = app.CallingApp.BPR;
                app.Xlabel = 'Rear brake pressure (bar)';
            elseif strcmp(value,'RPM')
                app.Xdata = app.CallingApp.RPM;
                app.Xlabel = 'Motor speed (rpm)';
            elseif strcmp(value,'Gear')
                app.Xdata = app.CallingApp.Gear;
                app.Xlabel = 'Engaged gear';
            elseif strcmp(value,'Pair')
                app.Xdata = app.CallingApp.Pair;
                app.Xlabel = 'Air pressure (bar)';
            elseif strcmp(value,'Tair')
                app.Xdata = app.CallingApp.Tair;
                app.Xlabel = 'Air temperature (°)';
            elseif strcmp(value,'Twater')
                app.Xdata = app.CallingApp.Twater;
                app.Xlabel = 'Water temperature (°)';
            elseif strcmp(value,'Poil')
                app.Xdata = app.CallingApp.Poil;
                app.Xlabel = 'Oil pressure (bar)';
            elseif strcmp(value,'Pfuel')
                app.Xdata = app.CallingApp.Pfuel;
                app.Xlabel = 'Puel pressure (bar)';
            elseif strcmp(value,'Lambda')
                app.Xdata = app.CallingApp.Lambda;
                app.Xlabel = 'Lambda';
            elseif strcmp(value,'TPS')
                app.Xdata = app.CallingApp.TPS;
                app.Xlabel = 'Throttle (%)';
            elseif strcmp(value,'Vbat')
                app.Xdata = app.CallingApp.Vbat;
                app.Xlabel = 'Battery voltage (V)';
            elseif strcmp(value,'Advance')
                app.Xdata = app.CallingApp.Advance;
                app.Xlabel = 'Spark advance (°)';
            elseif strcmp(value,'Injection')
                app.Xdata = app.CallingApp.Injection;
                app.Xlabel = 'Injection time (ms)';
            elseif strcmp(value,'FuelCons')
                app.Xdata = app.CallingApp.FuelCons;
                app.Xlabel = 'Fuel consumption (L/100km)';
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plot(app.UIAxes, app.Xdata, app.Ydata);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Trackplot.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {620, 620};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {31, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Trackplot = uifigure('Visible', 'off');
            app.Trackplot.Name = 'UI Figure';
            app.Trackplot.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Trackplot);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'X-Y plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.YDropDownLabel = uilabel(app.RightPanel);
            app.YDropDownLabel.Text = 'Y :';
            app.YDropDown = uidropdown(app.RightPanel);
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @YDropDownValueChanged, true);
            app.XYLabel = uilabel(app.RightPanel);
            app.XYLabel.Text = 'X-Y';
            app.XDropDownLabel = uilabel(app.RightPanel);
            app.XDropDownLabel.Text = 'X :';
            app.XDropDown = uidropdown(app.RightPanel);
            app.XDropDown.Items = {'Option 2', 'Option 3', 'Option 4'};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.XDropDown.Value = 'Option 2';
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.Trackplot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XY(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Trackplot)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Trackplot)
        end
    end
end