classdef datavisu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataVisu                        matlab.ui.Figure
        DisplayMenu                     matlab.ui.container.Menu
        tempsdistanceMenu               matlab.ui.container.Menu
        DisplayontrackMenu              matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        GForces                         matlab.ui.container.Menu
        CorneringMenu                   matlab.ui.container.Menu
        VitalFunctionsMenu              matlab.ui.container.Menu
        BrakingMenu                     matlab.ui.container.Menu
        GripfactorsMenu                 matlab.ui.container.Menu
        DampersMenu                     matlab.ui.container.Menu
        RollMenu                        matlab.ui.container.Menu
        XYMenu                          matlab.ui.container.Menu
        PiloteMenu                      matlab.ui.container.Menu
        LapTimesMenu                    matlab.ui.container.Menu
        GPS                             matlab.ui.control.UIAxes
        Duree                           matlab.ui.control.Label
        Vmax                            matlab.ui.control.Label
        Gxmax                           matlab.ui.control.Label
        Gxmin                           matlab.ui.control.Label
        Gymax                           matlab.ui.control.Label
        ExportdatatotheworkspaceButton  matlab.ui.control.Button
        Ttour                           matlab.ui.control.Label
        NbTour                          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        InProgress                      matlab.ui.control.Label
        ImportafilePanel                matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        FilenameLabel                   matlab.ui.control.Label
        NumberofanaloginputsDropDownLabel  matlab.ui.control.Label
        anag                            matlab.ui.control.DropDown
        LaptimeloggedCheckBox           matlab.ui.control.CheckBox
        ImportButton                    matlab.ui.control.Button
    end


    properties (Access = public)        
        Time = [];%en s
        Tacc = [];%en s
        Tgps = [];%en s
        X = [];%en m
        Y = [];%en m
        AccelX = [];%en G
        AccelY = [];%en G
        AccelZ = [];%en G
        Yaw = [];%en deg/s
        Pitch = [];%en deg/s
        Roll = [];%en deg/s
        Latitude = [];%en °
        Longitude = [];%en °
        Speed = [];%en km/h
        distance = [];% en m
        Altitude = [];%en m
        AccelX_GPS = [];%en G
        AccelY_GPS = [];%en G
        AccelZ_GPS = [];%en G
        Yaw_GPS = [];%en deg/s
        Pitch_GPS = [];%en deg/s
        Roll_GPS = [];%en deg/s
        Steering = [];%Angle de rotation du volant en radian
        XFL = [];%déplacement des suspensions en mm
        XFR = [];
        XRL = [];
        XRR = [];
        WFL = [];%Vitesse des roues en km/h
        WFR = [];
        WRL = [];
        WRR = [];
        BPF = [];%Pression dans le circuit de freinage en bar
        BPR = [];
        RPM = [];%vitesse de rotation du moteur en tour/min
        Gear = [];%Vitesse engangée (0 pour neutre)
        Pair = [];%pression dans le plenum en bar
        Tair = [];%Température dans le plenum en °c
        Twater = [];%Température d'eau en °c
        Poil = [];%Pression d'huile moteur
        Pfuel = [];%Pression dans le circuit d'essence au niveau des injecteurs
        Lambda = [];%Retour de la sonde lambda
        TPS = [];%déplacement de la guillotine en % (0 pour fermé et 100 pour ouvert)*
        Vbat = [];%tension de la batterie
        Advance = [] %avance du moteur en degré
        Injection = [] %temps d'injection en ms
        FuelCons = [] %conso d'essence en L/100
        Lap = [];% numéro du tour précédent
        LapTime = [];%temps du tour précédent
        Sector = [];%secteur dans lequel ce trouve la voiture ??
        SectorTime = [];%temps du secteur précédent ??
        ElapsedTime = [];%temps écoulé depuis le début du tour
        CurrentLap = [];%temps en cours  
        TLap = [];%Temps (à partir de Time) de début et de fin des tours (si 3 tours, 4 valeurs)
        
        
    end

    properties (Access = private)
        filename
        
        appCornering %application cornering
        Plot_app %application plot
        G_force %application Gforces
        appVital %application vital functions
        appBraking
        appDampers
        appTemps
        appGrip
        appRoll
        appTrackplot
        appXY
    end


    methods (Access = private)
        function [X,Y] = lat_longi2X_Y(~, lattitude, longitude)
            % lattitude et longitude sont les données brut en ° venant des logs
            lat_red = (lattitude - mean(lattitude))*pi/180;
            long_red = (longitude - mean(longitude))*pi/180;

            R = 6378137; % Rayon standard de la terre

            X = -R .* tan(lat_red);
            Y = R .* tan(long_red);
        end
    
        function [] = track_plot(app, X, Y, speed)
            % X et Y sont des coordonnées venant de lat_longi2X_Y
            % speed est en km/h
            cla(app.GPS,'reset') % reset le graph
            z = zeros(size(X'));
            col = speed';  % This is the color, vary with x in this case.
            surface(app.GPS, [X';X'],[Y';Y'],[z;z],[col;col],'facecol','no','edgecol','interp','linew',2);
            c = colorbar(app.GPS);
            xlabel(app.GPS, 'X (m)')
            ylabel(app.GPS, 'Y (m)')
            c.Label.String = 'Vitesse Km/h';
            axis(app.GPS,[min(app.X)-10,max(app.X)+10,min(app.Y)-10,max(app.Y)+10]);
            set(app.GPS,'Visible','off')
        end
        
        
        function steer = Fsteer(~, volt)
            for i=1:length(volt)
                if volt(i)<1
                    volt(i) = volt(i) + 2.61; %pour prendre en compte le comportement du capteur
                end
            end
            steer = 141.51*volt-ones(length(volt),1)*320.24; %Angle du volant à partir de la tension 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DataVisu.Name = 'DataVisu';
            app.InProgress.Text = 'Waiting for a file';
            app.ImportButton.Enable = 'off';
            app.ExportdatatotheworkspaceButton.Visible = 'off';
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Display
            app.InProgress.Text = strcat('importing',{' '},app.filename,'...');
            app.ImportButton.Enable = 'off';
            
            pause(0.01)
            
            %clear previous data
            
            clearvars Lap Laptime L
            
            
            % Number of analog input
            an = str2double(app.anag.Value);
            if app.LaptimeloggedCheckBox.Value
                nbgps = 10;
            else
                nbgps = 3;
            end
            
%             % Import the data en remplacant les vides par des NaN
%             raw  = dlmread_empty(app.filename,',',1,0,NaN);
%             
%             % Sélection des données enregistrée à 50 Hz
%             data50 = []; % données enregistrée à 50Hz
%             for i= 1:length(raw)
%                 if abs(raw(i,an+3)) >= 0 % On ne garde que les moments ou Les G sont pris
%                     data50 = [data50; raw(i,1:32+an)];
%                 end
%             end

            %Génération d'un Structure Array pour stocker les données
            data_struct = log2struct(app.filename);
            
            
            % GPS data selection
            % data_gps = [time:1 lattitude:2 longitude:3 speed:4 height:5 distance:6 Ax:7 Ay:8 Az:9 Yaw:10 Pitch:11 Roll:12]
            data_gps = []; 
            for i=1:length(raw) %pour toutes les données
                if abs(raw(i,an+33)) > 0   %Si le GPS ne s'initialise plus
                    GPSi = [raw(i,1) raw(i, an+33:an+33+nbgps)];
                    data_gps = [data_gps; GPSi];
                end
            end
            % data50 = [time(ms):1  Ax(g):9  Ay(g):10  Az(g):11  Yaw(°/s):12  Pitch(°/s):13  Roll(°/s):14]
            
            
            % Allocate imported array to column variable names
            app.Time = (raw(:,1)-raw(1,1))/1000; % enregistrement de Time en s
            %Accels
            app.Tacc = (data50(:,1)-data50(1,1))/1000; %en seconde et remis à zeros
            app.AccelX = -data50(:,an+3); %en G avec un moins car le boitier était à l'envers
            app.AccelY = data50(:,an+4); %en G
            app.AccelZ = data50(:,an+5); %en G
            app.Yaw = data50(:,an+6); %en deg/s
            app.Pitch = data50(:,an+7); %en deg/s
            app.Roll = data50(:,an+8); %en deg/s
            
            
            %Changelent de repère pour Optimus
            Ar = deg2rad(127); % angle d'inclinaison du RaceCapture
            R = [[cos(Ar)  sin(Ar)],
                 [-sin(Ar) cos(Ar)]];
            A = [app.AccelX, app.AccelZ]*R;
            app.AccelX = A(:,1);
            app.AccelZ = A(:,2);
            B = [app.Roll,app.Yaw]*R;
            app.Roll = B(:,1);
            app.Yaw = B(:,2);


            %Gps
            app.Tgps = (data_gps(:,1)-data50(1,1))/1000; %en seconde et remis au niveau des accels
            app.Latitude = data_gps(:,2);
            app.Longitude = data_gps(:,3);
            app.Speed = data_gps(:,4); %en km/h
            app.Altitude = data_gps(:,5)*0.3048; % en m
            if app.LaptimeloggedCheckBox.Value
                app.distance = data_gps(:,12)*1000; %en m
                app.Lap = data_gps(:,6); %nombre de tour effectué
                L = data_gps(:,7)*60; %temps du tour précédent en s
                app.Sector = data_gps(:,8); %secteur dans lequel se trouve la voiture
                app.SectorTime = data_gps(:,9); %temps pour le secteur précédent ?
                app.ElapsedTime = data_gps(:,10); %temps écoulé depuis le début du tour
                app.CurrentLap = data_gps(:,11); %numéro du tour en cours
            end
            
            app.AccelX_GPS = -interp1(app.Tacc,app.AccelX,app.Tgps); %en G avec un moins car le boitier était à l'envers
            app.AccelY_GPS = interp1(app.Tacc,app.AccelY,app.Tgps); %en G
            app.AccelZ_GPS = interp1(app.Tacc,app.AccelZ,app.Tgps); %en G
            app.Yaw_GPS = interp1(app.Tacc,app.Yaw,app.Tgps); %en deg/s
            app.Pitch_GPS = interp1(app.Tacc,app.Pitch,app.Tgps); %en deg/s
            app.Roll_GPS = interp1(app.Tacc,app.Roll,app.Tgps); %en deg/s
            %CAN
            app.RPM = data50(:,an+10); %en tour/min
            app.TPS = data50(:,an+11); 
            app.Twater = data50(:,an+12);
            app.Tair = data50(:,an+13);
            app.Pair = data50(:,an+14)/100; % en bar
            app.Lambda = data50(:,an+15)/1000; %unité classique (le can mulitiplie par 1000)
            app.Poil = data50(:,an+17)/100;
            app.Vbat = data50(:,an+20)/10; %en V
            app.WFL = data50(:,an+31)/10; 
            app.WFR = data50(:,an+29)/10; 
            app.WRL = data50(:,an+30)/10; 
            app.WRR = data50(:,an+28)/10;
            app.Gear = data50(:,an+22);
            app.Advance = data50(:,an+23)/10;
            app.Injection = data50(:,an+24)/100;
            app.FuelCons = data50(:,an+25)/10;
            app.Pfuel = data50(:,an+18)/100; %en bar
            app.BPR = data50(:,an+32);%*46.7/10000-ones(length(app.Pfuel),1)*5.6;
            
            %Analogique pour Optimus
            if an==2 
                app.BPF = data50(:,3);  %Pression en bars voir l'excel calib
                app.Steering = Fsteer(app,data50(:,4));
            end
            
            if an==6
                app.BPF = data50(:,3);  %Pression en bars voir l'excel calib
                volt = data50(:,4);
                app.Steering = Fsteer(app,volt);
                p = [16 160]; %210 a la place de 160 en vrai
                app.XFR = polyval(p,data50(:,5)); %Déplacement du capteur en mm
                app.XRR = polyval(p,data50(:,6));
                app.XFL = polyval(p,data50(:,7));
                app.XRL = polyval(p,data50(:,8));
                 %Déplacement de la suspension
                 
                %géométrie avant
                BCE_F = deg2rad(50.8);
                AC_F = 169.9/1000;
                CE_F = 85.2/1000;
                BC_F = 61.4/1000;
                
                %géométrie arrière
                BCE_R = deg2rad(48.7);
                AC_R = 176.7/1000;
                CE_R = 104.1/1000;
                BC_R = 82.4/1000;
                
                % calcul
%                 for i=1:length(app.XFR)
%                     ACB = acos((AC_F^2-CE_F^2-app.XFR(i)^2)/(2*AC_F*CE_F))-BCE_F;
%                     app.XFR(i)=sqrt(AC_F^2+BC_F^2-2*AC_F*BC_F*cos(ACB))*1000;
% 
% 		    ACB = acos((AC_F^2-CE_F^2-app.XFL(i)^2)/(2*AC_F*CE_F))-BCE_F;
%                     app.XFL(i)=sqrt(AC_F^2+BC_F^2-2*AC_F*BC_F*cos(ACB))*1000;
% 
% 		    ACB = acos((AC_R^2-CE_R^2-app.XRL(i)^2)/(2*AC_R*CE_R))-BCE_R;
%                     app.XRL(i)=sqrt(AC_R^2+BC_R^2-2*AC_R*BC_R*cos(ACB))*1000;
% 
% 		    ACB = acos((AC_R^2-CE_R^2-app.XRR(i)^2)/(2*AC_R*CE_R))-BCE_R;
%                     app.XRR(i)=sqrt(AC_R^2+BC_R^2-2*AC_R*BC_R*cos(ACB))*1000;
%                 end
                
            end
     
        
            % Clear temporary variables
            clearvars data50 data_gps raw A B
            
            %file informations
            if app.LaptimeloggedCheckBox.Value
                for k=1:length(app.ElapsedTime)-1
                    if (app.ElapsedTime(k)< 0.002 ) && (app.ElapsedTime(k+1)>0.002)
                        app.TLap = [app.TLap app.Tgps(k)];
                    end
                end
            end         
            
            %Duree
            s = mod(round(max(app.Time)),60); %secondes
            m = ( round(max(app.Time))-mod(round(max(app.Time)),60))/60; %minutes
            app.Duree.Text = strcat('Run duration  : ',{' '},num2str(m),' min ',num2str(s),' s');
            if app.LaptimeloggedCheckBox.Value
            %Nb de tour
                nb = app.Lap(end);
                app.NbTour.Text = strcat('Number of lap : ',{' '},num2str(nb));
                %meileur temps au tour
                 i=10;
                while length(app.LapTime)<nb
                     if app.Lap(i) == length(app.LapTime)+1
                         app.LapTime(length(app.LapTime)+1) = L(i);
                     end
                     i = i + 10;
                 end
                app.Ttour.Text = strcat('Best lap time :',{' '},num2str(min(app.LapTime),2),' s'); 
            else
                app.NbTour.Text = strcat('pas dispo');
                app.Ttour.Text = strcat('pas dispo');
            end
            
            %Vmax
            app.Vmax.Text = strcat('Maximum speed : ',{' '},num2str(round(max(app.Speed))),' km/h');
            %Gx max
            app.Gxmax.Text = strcat('Maximum longitudinal acceleration :',{' '},num2str(max(app.AccelX),2),' g');   
            %Gx min
            app.Gxmin.Text = strcat('Minimum longitudinal acceleration :',{' '},num2str(min(app.AccelX),2),' g');
            %Gy max
            app.Gymax.Text = strcat('Maximum lateral acceleration :',{' '},num2str(max(abs(app.AccelY)),2),' g');
            
            [app.X, app.Y] = lat_longi2X_Y(app, app.Latitude, app.Longitude);
            track_plot(app, app.X, app.Y, app.Speed)
            
            %Display
            app.InProgress.Text = strcat(app.filename,{' '},'was imported succesfully');
            app.ExportdatatotheworkspaceButton.Visible = 'on';
            app.ImportButton.Enable = 'off';
            
        end

        % Close request function: DataVisu
        function DataVisuCloseRequest(app, event)
            delete(app.Plot_app)
            delete(app.G_force)
            delete(app.appCornering)
            delete(app.appVital)
            delete(app.appBraking)
            delete(app.appTemps)
            delete(app.appGrip)
            delete(app.appRoll)
            delete(app.appDampers)
            delete(app.appTrackplot)
            delete(app.appXY)
            delete(app)
        end

        % Menu selected function: tempsdistanceMenu
        function tempsdistanceMenuSelected(app, event)
            app.Plot_app = Plot_Temps_Dist(app);
        end

        % Menu selected function: GForces
        function GForcesMenuSelected(app, event)
            app.G_force = Gforce(app);
        end

        % Menu selected function: CorneringMenu
        function CorneringMenuSelected(app, event)
            app.appCornering = Cornering(app);
        end

        % Button pushed function: ExportdatatotheworkspaceButton
        function ExportdatatotheworkspaceButtonPushed(app, event)
            assignin('base',"Time",app.Time);
            assignin('base',"Tacc",app.Tacc);
            assignin('base',"Tgps",app.Tgps);
            assignin('base',"X",app.X);
            assignin('base',"Y",app.Y);
            assignin('base',"AccelX",app.AccelX);
            assignin('base',"AccelY",app.AccelY);
            assignin('base',"AccelZ",app.AccelZ);
            assignin('base',"Yaw",app.Yaw);
            assignin('base',"Pitch",app.Pitch);
            assignin('base',"Roll",app.Roll);
            assignin('base',"AccelX_GPS",app.AccelX_GPS);
            assignin('base',"AccelY_GPS",app.AccelY_GPS);
            assignin('base',"AccelZ_GPS",app.AccelZ_GPS);
            assignin('base',"Yaw_GPS",app.Yaw_GPS);
            assignin('base',"Pitch_GPS",app.Pitch_GPS);
            assignin('base',"Roll_GPS",app.Roll_GPS);
            assignin('base',"Latitude",app.Latitude);
            assignin('base',"Longitude",app.Longitude);
            assignin('base',"Speed",app.Speed);
            assignin('base',"distance",app.distance);
            assignin('base',"Altitude",app.Altitude);
            assignin('base',"Steering",app.Steering);
            assignin('base',"XFL",app.XFL);
            assignin('base',"XFR",app.XFR);
            assignin('base',"XRL",app.XRL);
            assignin('base',"XRR",app.XRR);
            assignin('base',"WFL",app.WFL);
            assignin('base',"WFR",app.WFR);
            assignin('base',"WRL",app.WRL);
            assignin('base',"WRR",app.WRR);
            assignin('base',"BPF",app.BPF);
            assignin('base',"BPR",app.BPR);
            assignin('base',"RPM",app.RPM);
            assignin('base',"Gear",app.Gear);
            assignin('base',"Pair",app.Pair);
            assignin('base',"Tair",app.Tair);
            assignin('base',"Twater",app.Twater);
            assignin('base',"Poil",app.Poil);
            assignin('base',"Pfuel",app.Pfuel);
            assignin('base',"Lambda",app.Lambda);
            assignin('base',"TPS",app.TPS);
            assignin('base',"Vbat",app.Vbat);
            assignin('base',"Lap",app.Lap);
            assignin('base',"LapTime",app.LapTime);
            assignin('base',"Sector",app.Sector);
            assignin('base',"SectorTime",app.SectorTime);
            assignin('base',"ElapsedTime",app.ElapsedTime);
            assignin('base',"CurrentLap",app.CurrentLap);
            assignin('base',"TLap",app.TLap);
            assignin('base',"Advance",app.Advance);
            assignin('base',"Injection",app.Injection);
            assignin('base',"FuelCons",app.FuelCons);
        end

        % Menu selected function: VitalFunctionsMenu
        function VitalFunctionsMenuSelected(app, event)
            app.appVital = Vital(app);
        end

        % Menu selected function: BrakingMenu
        function BrakingMenuSelected(app, event)
            app.appBraking = Braking(app);
        end

        % Menu selected function: LapTimesMenu
        function LapTimesMenuSelected(app, event)
            app.appTemps = Temps(app);
        end

        % Menu selected function: GripfactorsMenu
        function GripfactorsMenuSelected(app, event)
            app.appGrip = GripFactor(app);
        end

        % Menu selected function: DampersMenu
        function DampersMenuSelected(app, event)
            app.appDampers = Dampers(app);
        end

        % Menu selected function: RollMenu
        function RollMenuSelected(app, event)
            app.appRoll = Roll_Gradient(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
             [file,path] =  uigetfile('*.log','Select a log file');
              figure(app.DataVisu);
             if file ~= 0 
                 if strcmp('.log',file(end-3:end))
                    app.filename = fullfile(path,file);
                    app.ImportButton.Enable = 'on';
                    app.FilenameLabel.Text = strcat('File name :',{' '},file);
                    app.InProgress.Text = strcat(app.filename,{' '},' is ready for importation');
                 else
                     app.InProgress.text = 'You have to import a log file';
                 end
             end
        end

        % Menu selected function: DisplayontrackMenu
        function DisplayontrackMenuSelected(app, event)
            app.appTrackplot = Track_plot(app);
        end

        % Menu selected function: XYMenu
        function XYMenuSelected(app, event)
            app.appXY = XY(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataVisu = uifigure('Visible', 'off');
            app.DataVisu.Name = 'UI Figure';
            app.DataVisu.CloseRequestFcn = createCallbackFcn(app, @DataVisuCloseRequest, true);
            app.DisplayMenu = uimenu(app.DataVisu);
            app.DisplayMenu.Text = 'Display';
            app.tempsdistanceMenu = uimenu(app.DisplayMenu);
            app.tempsdistanceMenu.MenuSelectedFcn = createCallbackFcn(app, @tempsdistanceMenuSelected, true);
            app.tempsdistanceMenu.Text = '/temps,distance';
            app.DisplayontrackMenu = uimenu(app.DisplayMenu);
            app.DisplayontrackMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplayontrackMenuSelected, true);
            app.DisplayontrackMenu.Text = 'Display on track';
            app.AnalysisMenu = uimenu(app.DataVisu);
            app.AnalysisMenu.Text = 'Analysis';
            app.GForces = uimenu(app.AnalysisMenu);
            app.GForces.MenuSelectedFcn = createCallbackFcn(app, @GForcesMenuSelected, true);
            app.GForces.Text = 'G-Forces';
            app.CorneringMenu = uimenu(app.AnalysisMenu);
            app.CorneringMenu.MenuSelectedFcn = createCallbackFcn(app, @CorneringMenuSelected, true);
            app.CorneringMenu.Text = 'Cornering';
            app.VitalFunctionsMenu = uimenu(app.AnalysisMenu);
            app.VitalFunctionsMenu.MenuSelectedFcn = createCallbackFcn(app, @VitalFunctionsMenuSelected, true);
            app.VitalFunctionsMenu.Text = 'Vital Functions';
            app.BrakingMenu = uimenu(app.AnalysisMenu);
            app.BrakingMenu.MenuSelectedFcn = createCallbackFcn(app, @BrakingMenuSelected, true);
            app.BrakingMenu.Text = 'Braking';
            app.GripfactorsMenu = uimenu(app.AnalysisMenu);
            app.GripfactorsMenu.MenuSelectedFcn = createCallbackFcn(app, @GripfactorsMenuSelected, true);
            app.GripfactorsMenu.Text = 'Grip factors';
            app.DampersMenu = uimenu(app.AnalysisMenu);
            app.DampersMenu.MenuSelectedFcn = createCallbackFcn(app, @DampersMenuSelected, true);
            app.DampersMenu.Text = 'Dampers';
            app.RollMenu = uimenu(app.AnalysisMenu);
            app.RollMenu.MenuSelectedFcn = createCallbackFcn(app, @RollMenuSelected, true);
            app.RollMenu.Text = 'Roll ';
            app.XYMenu = uimenu(app.AnalysisMenu);
            app.XYMenu.MenuSelectedFcn = createCallbackFcn(app, @XYMenuSelected, true);
            app.XYMenu.Text = 'X-Y';
            app.PiloteMenu = uimenu(app.DataVisu);
            app.PiloteMenu.Text = 'Pilote';
            app.LapTimesMenu = uimenu(app.PiloteMenu);
            app.LapTimesMenu.MenuSelectedFcn = createCallbackFcn(app, @LapTimesMenuSelected, true);
            app.LapTimesMenu.Text = 'Lap Times';
            app.GPS = uiaxes(app.DataVisu);
            title(app.GPS, 'Coordonnées GPS')
            app.Duree = uilabel(app.DataVisu);
            app.Duree.Text = 'Run duration : ';
            app.Vmax = uilabel(app.DataVisu);
            app.Vmax.Text = 'Maximuml speed :';
            app.Gxmax = uilabel(app.DataVisu);
            app.Gxmax.Text = 'Maximum longitudinal acceleration :';
            app.Gxmin = uilabel(app.DataVisu);
            app.Gxmin.Text = 'Minimum longitudinale acceleration :';
            app.Gymax = uilabel(app.DataVisu);
            app.Gymax.Text = 'Maximum lateral acceleration :';
            app.ExportdatatotheworkspaceButton = uibutton(app.DataVisu, 'push');
            app.ExportdatatotheworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdatatotheworkspaceButtonPushed, true);
            app.ExportdatatotheworkspaceButton.Text = 'Export data to the workspace';
            app.Ttour = uilabel(app.DataVisu);
            app.Ttour.Text = 'Best lap time :';
            app.NbTour = uilabel(app.DataVisu);
            app.NbTour.Text = 'Number of lap :';
            app.Image = uiimage(app.DataVisu);
            app.Image.ImageSource = 'logo.png';
            app.InProgress = uilabel(app.DataVisu);
            app.InProgress.Text = 'InProgress';
            app.ImportafilePanel = uipanel(app.DataVisu);
            app.ImportafilePanel.Title = 'Import a file';
            app.BrowseButton = uibutton(app.ImportafilePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FilenameLabel = uilabel(app.ImportafilePanel);
            app.FilenameLabel.Text = 'File name :';
            app.NumberofanaloginputsDropDownLabel = uilabel(app.ImportafilePanel);
            app.NumberofanaloginputsDropDownLabel.Text = 'Number of analog inputs :';
            app.anag = uidropdown(app.ImportafilePanel);
            app.anag.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8'};
            app.anag.Value = '6';
            app.LaptimeloggedCheckBox = uicheckbox(app.ImportafilePanel);
            app.LaptimeloggedCheckBox.Text = 'Lap time logged';
            app.LaptimeloggedCheckBox.Value = true;
            app.ImportButton = uibutton(app.ImportafilePanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            % Show the figure after all components are created
            app.DataVisu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = datavisu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataVisu)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataVisu)
        end
    end
end